classdef Eduardo_PDS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        XminEditField_2       matlab.ui.control.EditField
        XminEditField_2Label  matlab.ui.control.Label
        XmaxEditField_2       matlab.ui.control.EditField
        XmaxEditField_2Label  matlab.ui.control.Label
        rEditField_2          matlab.ui.control.EditField
        rEditField_2Label     matlab.ui.control.Label
        rEditField            matlab.ui.control.EditField
        rEditFieldLabel       matlab.ui.control.Label
        XminEditField         matlab.ui.control.EditField
        XminEditFieldLabel    matlab.ui.control.Label
        XmaxEditField         matlab.ui.control.EditField
        XmaxEditFieldLabel    matlab.ui.control.Label
        GraficarButton        matlab.ui.control.Button
        GraficasButton        matlab.ui.control.Button
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficasButton
        function GraficasButtonPushed(app, event)
 Xmin = app.XminEditField.Value;
Xmax = app.XmaxEditField.Value;
r_rise = app.rEditField.Value;

if isscalar(Xmin) && isscalar(Xmax) && isscalar(r_rise) && Xmin < Xmax
    max_abs = max(abs(Xmin), abs(Xmax));
    Xmin = -max_abs;
    Xmax = max_abs;

    L_rise = 2^r_rise;
    Delta_rise = (Xmax - Xmin) / L_rise;

    x = linspace(Xmin - 0.2, Xmax + 0.2, 1000);
    q_rise = floor((x - Xmin) / Delta_rise);
    q = q_rise * Delta_rise + Xmin + Delta_rise / 2;

    cla(app.UIAxes2);
    stairs(app.UIAxes2, x, q, 'Color', [0 0.6 0.6], 'LineWidth', 2)
    hold(app.UIAxes2, 'on')
    xline(app.UIAxes2, 0, '--k', 'LineWidth', 1.5)
    yline(app.UIAxes2, 0, '--k', 'LineWidth', 1.5)
    hold(app.UIAxes2, 'off')

    title(app.UIAxes2, ['MID-RISE (r = ', num2str(r_rise), ' bits)'])
    xlabel(app.UIAxes2, 'Entrada')
    ylabel(app.UIAxes2, 'Salida cuantificada')
    grid(app.UIAxes2, 'on')
end

        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
          Xmin = app.XminTreadEditField.Value;
    Xmax = app.XmaxTreadEditField.Value;
    r = app.rTreadEditField.Value;


        L_tread = 2^r_tread;
        Delta_tread = (Xmax- Xmin) / L;
        x = linspace(Xmin, Xmax, 1000);
        q = floor((x - Xmin) / Delta + 0.5);
        q = q * Delta + Xmin;

        % Graficar con STAIRS (MID-TREAD)
        cla(app.UIAxesTread); % Limpiar eje antes de graficar
        stairs(app.UIAxesTread, x, q, 'Color', [0.8 0.2 0.2], 'LineWidth', 2)
        hold(app.UIAxesTread, 'on')
        xline(app.UIAxesTread, 0, '--k', 'LineWidth', 1.5)
        yline(app.UIAxesTread, 0, '--k', 'LineWidth', 1.5)
        hold(app.UIAxesTread, 'off')
        title(app.UIAxesTread, 'MID-TREAD')
        xlabel(app.UIAxesTread, 'Entrada')
        ylabel(app.UIAxesTread, 'Salida cuantificada')
        grid(app.UIAxesTread, 'on')    
        end

        % Value changed function: XminEditField
        function XminEditFieldValueChanged(app, event)
            value = app.XminEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GraficasButton = uibutton(app.UIFigure, 'push');
            app.GraficasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficasButtonPushed, true);
            app.GraficasButton.Text = 'Graficas';
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.UIFigure, 'text');
            app.XminEditFieldLabel = uilabel(app.UIFigure);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.UIFigure, 'text');
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @XminEditFieldValueChanged, true);
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.UIFigure, 'text');
            app.rEditField_2Label = uilabel(app.UIFigure);
            app.rEditField_2Label.Text = 'r';
            app.rEditField_2 = uieditfield(app.UIFigure, 'text');
            app.XmaxEditField_2Label = uilabel(app.UIFigure);
            app.XmaxEditField_2Label.Text = 'Xmax';
            app.XmaxEditField_2 = uieditfield(app.UIFigure, 'text');
            app.XminEditField_2Label = uilabel(app.UIFigure);
            app.XminEditField_2Label.Text = 'Xmin';
            app.XminEditField_2 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Eduardo_PDS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end