classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ReproducirButton_2        matlab.ui.control.Button
        SealenelTiempoButton_2    matlab.ui.control.Button
        Image3                    matlab.ui.control.Image
        TabGroup2_2               matlab.ui.container.TabGroup
        Tab_3                     matlab.ui.container.Tab
        SNRBLOQUE_ADAPButton      matlab.ui.control.Button
        SNRTOTAL_ADAPButton       matlab.ui.control.Button
        SNRBLOQUE_FIJOButton      matlab.ui.control.Button
        SNRTOTAL_FIJOButton       matlab.ui.control.Button
        TabGroup2                 matlab.ui.container.TabGroup
        Tab_2                     matlab.ui.container.Tab
        AdaptativoButton          matlab.ui.control.Button
        ReproducirButton          matlab.ui.control.Button
        EspectrodeMagnitudButton  matlab.ui.control.Button
        rp7Button                 matlab.ui.control.Button
        rp6Button                 matlab.ui.control.Button
        rp5Button                 matlab.ui.control.Button
        rp4Button                 matlab.ui.control.Button
        rp3Button                 matlab.ui.control.Button
        rp2Button                 matlab.ui.control.Button
        rp1Button                 matlab.ui.control.Button
        EspectrogramaButton       matlab.ui.control.Button
        SealenelTiempoButton      matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        rp5EditField              matlab.ui.control.NumericEditField
        rp5EditFieldLabel         matlab.ui.control.Label
        rp4EditField              matlab.ui.control.NumericEditField
        rp4EditFieldLabel         matlab.ui.control.Label
        rp3EditField              matlab.ui.control.NumericEditField
        rp3EditFieldLabel         matlab.ui.control.Label
        rp2EditField              matlab.ui.control.NumericEditField
        rp2EditFieldLabel         matlab.ui.control.Label
        rp1EditField              matlab.ui.control.NumericEditField
        rp1EditFieldLabel         matlab.ui.control.Label
        TcEditField_2             matlab.ui.control.NumericEditField
        TcEditField_2Label        matlab.ui.control.Label
        Image2                    matlab.ui.control.Image
        ID_OutputEditField        matlab.ui.control.NumericEditField
        ID_OutputEditFieldLabel   matlab.ui.control.Label
        ID_ImputEditField         matlab.ui.control.NumericEditField
        ID_ImputEditFieldLabel    matlab.ui.control.Label
        rEditField                matlab.ui.control.NumericEditField
        rEditFieldLabel           matlab.ui.control.Label
        FsEditField               matlab.ui.control.NumericEditField
        FsEditFieldLabel          matlab.ui.control.Label
        UIAxes_6                  matlab.ui.control.UIAxes
        UIAxes_5                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image2
        function Image2Clicked(app, event)
           global Fs 
           Tc = app.FsEditField.Value(); 
           Fs = 1/Tc;
           r = 24;
        end

        % Button down function: UIAxes_3
        function UIAxes_3ButtonDown(app, event)


        end

        % Button pushed function: SealenelTiempoButton
        function SealenelTiempoButtonPushed(app, event)
          global x t
            
          T = 1/Fs;
          N = length(x);% tamañototal del vector
          Tc = N/Fs;
          t = 0:T:Tc-T; 

          %Ecuación de la Onda
          x= 0.7*cos(2*pi*100*t+pi/8);

          %Analisis grafico en el tiempo
          app.UIAxes.Visible = 'on';
          cla(app.UIAxes,"reset")
          plot(app.UIAxes,t,x,LineWidth=2);
          %Plot simula continuidad, une punto 1 con punto 2
          title(app.UIAxes,"Señal en el Tiempo");
          xlabel(app.UIAxes,"Tiempo (s)");
          ylabel(app.UIAxes,"Amplitud(V)");
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @UIAxes_3ButtonDown, true);
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.FsEditFieldLabel = uilabel(app.Tab);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.Tab, 'numeric');
            app.FsEditField.Value = 8000;
            app.rEditFieldLabel = uilabel(app.Tab);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Tab, 'numeric');
            app.rEditField.Value = 24;
            app.ID_ImputEditFieldLabel = uilabel(app.Tab);
            app.ID_ImputEditFieldLabel.Text = 'ID_Imput';
            app.ID_ImputEditField = uieditfield(app.Tab, 'numeric');
            app.ID_OutputEditFieldLabel = uilabel(app.Tab);
            app.ID_OutputEditFieldLabel.Text = 'ID_Output';
            app.ID_OutputEditField = uieditfield(app.Tab, 'numeric');
            app.Image2 = uiimage(app.Tab);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'descargar.jpeg');
            app.TcEditField_2Label = uilabel(app.Tab);
            app.TcEditField_2Label.Text = 'Tc';
            app.TcEditField_2 = uieditfield(app.Tab, 'numeric');
            app.TcEditField_2.Value = 6;
            app.rp1EditFieldLabel = uilabel(app.Tab);
            app.rp1EditFieldLabel.Text = 'rp1';
            app.rp1EditField = uieditfield(app.Tab, 'numeric');
            app.rp1EditField.Value = 2;
            app.rp2EditFieldLabel = uilabel(app.Tab);
            app.rp2EditFieldLabel.Text = 'rp2';
            app.rp2EditField = uieditfield(app.Tab, 'numeric');
            app.rp2EditField.Value = 4;
            app.rp3EditFieldLabel = uilabel(app.Tab);
            app.rp3EditFieldLabel.Text = 'rp3';
            app.rp3EditField = uieditfield(app.Tab, 'numeric');
            app.rp3EditField.Value = 8;
            app.rp4EditFieldLabel = uilabel(app.Tab);
            app.rp4EditFieldLabel.Text = 'rp4';
            app.rp4EditField = uieditfield(app.Tab, 'numeric');
            app.rp4EditField.Value = 16;
            app.rp5EditFieldLabel = uilabel(app.Tab);
            app.rp5EditFieldLabel.Text = 'rp5';
            app.rp5EditField = uieditfield(app.Tab, 'numeric');
            app.rp5EditField.Value = 32;
            app.SealenelTiempoButton = uibutton(app.UIFigure, 'push');
            app.SealenelTiempoButton.ButtonPushedFcn = createCallbackFcn(app, @SealenelTiempoButtonPushed, true);
            app.SealenelTiempoButton.Text = 'Señal en el Tiempo';
            app.EspectrogramaButton = uibutton(app.UIFigure, 'push');
            app.EspectrogramaButton.Text = 'Espectrograma';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'Tab';
            app.rp1Button = uibutton(app.Tab_2, 'push');
            app.rp1Button.Text = 'rp1';
            app.rp2Button = uibutton(app.Tab_2, 'push');
            app.rp2Button.Text = 'rp2';
            app.rp3Button = uibutton(app.Tab_2, 'push');
            app.rp3Button.Text = 'rp3';
            app.rp4Button = uibutton(app.Tab_2, 'push');
            app.rp4Button.Text = 'rp4';
            app.rp5Button = uibutton(app.Tab_2, 'push');
            app.rp5Button.Text = 'rp5';
            app.rp6Button = uibutton(app.Tab_2, 'push');
            app.rp6Button.Text = 'rp6';
            app.rp7Button = uibutton(app.Tab_2, 'push');
            app.rp7Button.Text = 'rp7';
            app.EspectrodeMagnitudButton = uibutton(app.Tab_2, 'push');
            app.EspectrodeMagnitudButton.Text = 'Espectro de Magnitud';
            app.ReproducirButton = uibutton(app.Tab_2, 'push');
            app.ReproducirButton.Text = 'Reproducir';
            app.AdaptativoButton = uibutton(app.Tab_2, 'push');
            app.AdaptativoButton.Text = 'Adaptativo';
            app.TabGroup2_2 = uitabgroup(app.UIFigure);
            app.Tab_3 = uitab(app.TabGroup2_2);
            app.Tab_3.Title = 'Tab';
            app.SNRTOTAL_FIJOButton = uibutton(app.Tab_3, 'push');
            app.SNRTOTAL_FIJOButton.Text = 'SNR TOTAL_FIJO';
            app.SNRBLOQUE_FIJOButton = uibutton(app.Tab_3, 'push');
            app.SNRBLOQUE_FIJOButton.Text = 'SNR BLOQUE_FIJO';
            app.SNRTOTAL_ADAPButton = uibutton(app.Tab_3, 'push');
            app.SNRTOTAL_ADAPButton.Text = 'SNR TOTAL_ADAP';
            app.SNRBLOQUE_ADAPButton = uibutton(app.Tab_3, 'push');
            app.SNRBLOQUE_ADAPButton.Text = 'SNR BLOQUE_ADAP';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Logo_oficial_de_Universitario.png');
            app.SealenelTiempoButton_2 = uibutton(app.UIFigure, 'push');
            app.SealenelTiempoButton_2.Text = 'Señal en el Tiempo';
            app.ReproducirButton_2 = uibutton(app.UIFigure, 'push');
            app.ReproducirButton_2.Text = 'Reproducir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end