classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup2_2               matlab.ui.container.TabGroup
        Tab_3                     matlab.ui.container.Tab
        SNRBloqueButton_2         matlab.ui.control.Button
        SNRTotalButton_2          matlab.ui.control.Button
        ReproducirButton_3        matlab.ui.control.Button
        TiempoButton_2            matlab.ui.control.Button
        rp7Button_2               matlab.ui.control.Button
        rp6Button_2               matlab.ui.control.Button
        rp5Button_2               matlab.ui.control.Button
        rp4Button_2               matlab.ui.control.Button
        rp3Button_2               matlab.ui.control.Button
        rp2Button_2               matlab.ui.control.Button
        rp1Button_2               matlab.ui.control.Button
        TabGroup2                 matlab.ui.container.TabGroup
        Tab_2                     matlab.ui.container.Tab
        SNRBloqueButton           matlab.ui.control.Button
        SNRTotalButton            matlab.ui.control.Button
        ReproducirButton_2        matlab.ui.control.Button
        TiempoButton              matlab.ui.control.Button
        rp7Button                 matlab.ui.control.Button
        rp6Button                 matlab.ui.control.Button
        rp5Button                 matlab.ui.control.Button
        rp4Button                 matlab.ui.control.Button
        rp3Button                 matlab.ui.control.Button
        rp2Button                 matlab.ui.control.Button
        rp1Button                 matlab.ui.control.Button
        Image3                    matlab.ui.control.Image
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        ReproducirButton          matlab.ui.control.Button
        EstrogramaButton          matlab.ui.control.Button
        EspectrodeMagnitudButton  matlab.ui.control.Button
        SealenelTiempoButton      matlab.ui.control.Button
        rp5EditField_3            matlab.ui.control.NumericEditField
        rp5EditField_3Label       matlab.ui.control.Label
        rp6EditField              matlab.ui.control.NumericEditField
        rp6EditFieldLabel         matlab.ui.control.Label
        rp7EditField              matlab.ui.control.NumericEditField
        rp7EditFieldLabel         matlab.ui.control.Label
        rp4EditField              matlab.ui.control.NumericEditField
        rp4EditFieldLabel         matlab.ui.control.Label
        rp3EditField              matlab.ui.control.NumericEditField
        rp3EditFieldLabel         matlab.ui.control.Label
        rp2EditField              matlab.ui.control.NumericEditField
        rp2EditFieldLabel         matlab.ui.control.Label
        rp1EditField              matlab.ui.control.NumericEditField
        rp1EditFieldLabel         matlab.ui.control.Label
        TcEditField_2             matlab.ui.control.NumericEditField
        TcEditField_2Label        matlab.ui.control.Label
        microfono                 matlab.ui.control.Image
        ID_OutputEditField        matlab.ui.control.NumericEditField
        ID_OutputEditFieldLabel   matlab.ui.control.Label
        ID_ImputEditField         matlab.ui.control.NumericEditField
        ID_ImputEditFieldLabel    matlab.ui.control.Label
        rEditField                matlab.ui.control.NumericEditField
        rEditFieldLabel           matlab.ui.control.Label
        FsEditField               matlab.ui.control.NumericEditField
        FsEditFieldLabel          matlab.ui.control.Label
        UIAxes7_2                 matlab.ui.control.UIAxes
        UIAxes7                   matlab.ui.control.UIAxes
        UIAxes3_6                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: microfono
        function microfonoClicked(app, event)
 %GRABA AUDIO
            global x t 
            Fs= app.FsEditField.Value();
            r= app.rEditField.Value();  
            Tc= app.TcEditField_2.Value();  
            ID_Imput= app.ID_ImputEditField.Value();  
           % fe=1;
            T_V = 30/1000;
           % T_V_A = 30/1000;
           % N_T_V = T_V*Fs;
           % P = T_V_A*Fs;
            a= audiodevinfo();
            %Proceso de captura
            grabadora= audiorecorder(Fs,r,1,ID_Imput);
            record(grabadora,Tc);
            pause(Tc+2);
            x=getaudiodata(grabadora,"double");
            T= 1/Fs;
            t = 0:T:Tc-T;
            app.UIAxes3_6.Visible="on";
            cla(app.UIAxes3_6,"reset");
            plot(app.UIAxes3_6,t,x);xlabel(app.UIAxes3_6,"tiempo");ylabel(app.UIAxes3_6,"Amplitud");
            title(app.UIAxes3_6,"Señal de entrada");
          
        end

        % Button pushed function: SealenelTiempoButton
        function SealenelTiempoButtonPushed(app, event)
 
            global x t y
            
            % Gráfica de la señal de tiempo
            app.UIAxes3_6.Visible = "on";
            cla(app.UIAxes3_6, "reset");
            plot(app.UIAxes3_6, t, x); xlabel(app.UIAxes3_6, "Tiempo (s)"); ylabel(app.UIAxes3_6, "Amplitud");
            title(app.UIAxes3_6, "Señal de entrada");

        end

        % Callback function: not associated with a component
        function FijoButtonPushed(app, event)
            %FIJO
xr.fijotsnr1 = fsnr(x,xr.fijo1);
xr.fijotsnr2 = fsnr(x,xr.fijo2);
xr.fijotsnr3 = fsnr(x,xr.fijo3);
xr.fijotsnr4 = fsnr(x,xr.fijo4);
xr.fijotsnr5 = fsnr(x,xr.fijo5);
xr.fijotsnr6 = fsnr(x,xr.fijo6);
xr.fijotsnr7 = fsnr(x,xr.fijo7);

        end

        % Callback function: not associated with a component
        function ButtonPushed2(app, event)
            reproductor = audioplayer(x,Fs/2,r,ID_Output);
play(reproductor)
        end

        % Callback function: not associated with a component
        function ButtonPushed(app, event)
            [X,FREC] = fourier(X,Fs);

Figure();
plot(FREC,abs(X));title("Espectro de la señal de entrada");
xlabel("Frecuencia (HZ)");
        end

        % Callback function: not associated with a component
        function SNRBLOUEButtonPushed(app, event)
            %SNR BLOQUE
T_V = 30/1000; %tiemnpo de duracion del bloque en ms 
xr.fijobsnr1 = snr_bloque(x,xr.fijo1,T_V,Fs);
xr.fijobsnr2 = snr_bloque(x,xr.fijo2,T_V,Fs);
xr.fijobsnr3 = snr_bloque(x,xr.fijo3,T_V,Fs);
xr.fijobsnr4 = snr_bloque(x,xr.fijo4,T_V,Fs);
xr.fijobsnr5 = snr_bloque(x,xr.fijo5,T_V,Fs);
xr.fijobsnr6 = snr_bloque(x,xr.fijo6,T_V,Fs);
xr.fijobsnr7 = snr_bloque(x,xr.fijo7,T_V,Fs);

        end

        % Callback function: not associated with a component
        function SNRTOTALButtonPushed(app, event)
            %mas alto snr // por bloques
vsnrfijo = [xr.fijotsnr1 xr.fijotsnr2 xr.fijotsnr3...
    xr.fijotsnr4 xr.fijotsnr5 xr.fijotsnr6 xr.fijotsnr7];

figure();
subplot(3,3,1);plot(t,xr.fijo1);title(["rp:" num2str(rp(1))]);
subplot(3,3,2);plot(t,xr.fijo2);title(["rp:" num2str(rp(2))]);
subplot(3,3,3);plot(t,xr.fijo3);title(["rp:" num2str(rp(3))]);
subplot(3,3,4);plot(t,xr.fijo4);title(["rp:" num2str(rp(4))]);
subplot(3,3,5);plot(t,xr.fijo5);title(["rp:" num2str(rp(5))]);
subplot(3,3,6);plot(t,xr.fijo6);title(["rp:" num2str(rp(6))]);
subplot(3,3,7);plot(t,xr.fijo7);title(["rp:" num2str(rp(7))]);

subplot(3,3,8);stem(rp,vsnrfijo);title("SNR Total");

subplot(3,3,9);plot(xr.fijobsnr1);title("SNR por Bloques");hold on;
plot(xr.fijobsnr2);
plot(xr.fijobsnr3);
plot(xr.fijobsnr4);
plot(xr.fijobsnr5);
plot(xr.fijobsnr6);
plot(xr.fijobsnr7);
        end

        % Button pushed function: EspectrodeMagnitudButton
        function EspectrodeMagnitudButtonPushed(app, event)
            global x t y
            % --- Gráfica del espectro de magnitud ---
            Fs= app.FsEditField.Value();
            app.UIAxes3_6.Visible = "on"; % Supongamos que tienes un tercer eje
            cla(app.UIAxes3_6, "reset");
            n = length(x);
            X = fft(x);
            f = (0:n-1)*(Fs/n); % Frecuencias
            magnitude = abs(X/n); % Magnitud normalizada
            plot(app.UIAxes3_6, f(1:floor(n/2)), magnitude(1:floor(n/2))); xlabel(app.UIAxes3_6, "Frecuencia (Hz)");
            ylabel(app.UIAxes3_6, "Magnitud");title(app.UIAxes3_6, "Espectro de magnitud");

        end

        % Button pushed function: EstrogramaButton
        function EstrogramaButtonPushed(app, event)
             global x t % Usamos la señal grabada y el vector de tiempo
 % Obtener la frecuencia de muestreo desde el campo de texto
    Fs = app.FsEditField.Value();

    % Mostrar el eje y limpiar la figura anterior
    app.UIAxes3_6.Visible = "on"; 
    cla(app.UIAxes3_6, "reset");

    % Parámetros del espectrograma
    window = 256;                         % Tamaño de la ventana
    noverlap = round(window * 0.5);       % Solapamiento (50%)
    nfft = 512;                           % Número de puntos FFT

    % Calcular el espectrograma de la señal grabada
    [S, F, T_spec, P] = spectrogram(x, window, noverlap, nfft, Fs);

    % Mostrar el espectrograma en escala logarítmica
    imagesc(app.UIAxes3_6, T_spec, F, 10*log10(P));
    axis(app.UIAxes3_6, 'xy');  % Poner el origen en la esquina inferior izquierda
    xlabel(app.UIAxes3_6, 'Tiempo (s)');
    ylabel(app.UIAxes3_6, 'Frecuencia (Hz)');
    title(app.UIAxes3_6, 'Espectrograma de la señal grabada');

    % Estética del gráfico
    colormap(app.UIAxes3_6, 'jet');      % Colormap tipo "jet"
    colorbar(app.UIAxes3_6);             % Barra de colores a la derecha
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
             %REPRODUCE AUDIO
            global x t
            Fs = app.FsEditField.Value();
            % Reproduce la señal original
            sound(x, Fs);
            pause(length(x) / Fs + 1); % Pausa hasta que termine la reproducción
        end

        % Button pushed function: rp1Button
        function rp1ButtonPushed(app, event)
            global x t 
            Fs= app.FsEditField.Value();
            rp = [app.rp1EditField.Value(),app.rp2EditField.Value(),app.rp3EditField.Value(),app.rp4EditField.Value(),app.rp5EditField_3.Value(),app.rp6EditField.Value(),app.rp7EditField.Value()];
            xr.fijo1 = rfijo(x,rp(1));
            T_V = 30/1000; %tiemnpo de duracion del bloque en ms
            xr.fijobsnr1 = snr_bloque(x,xr.fijo1,T_V,Fs);

            app.UIAxes7.Visible="on";
            plot(app.UIAxes7,xr.fijo1);title(app.UIAxes7,"rp = 1" );
        end

        % Button pushed function: rp2Button
        function rp2ButtonPushed(app, event)
             global x t 
            Fs= app.FsEditField.Value();
            rp = [app.rp1EditField.Value(),app.rp2EditField.Value(),app.rp3EditField.Value(),app.rp4EditField.Value(),app.rp5EditField_3.Value(),app.rp6EditField.Value(),app.rp7EditField.Value()];
            xr.fijo2 = rfijo(x,rp(2));
            T_V = 30/1000; %tiemnpo de duracion del bloque en ms
            xr.fijobsnr2 = snr_bloque(x,xr.fijo2,T_V,Fs);
            app.UIAxes7.Visible="on";
            plot(app.UIAxes7,xr.fijo2);title(app.UIAxes7,"rp = 2" );
        end

        % Button pushed function: rp3Button
        function rp3ButtonPushed(app, event)
             global x t 
            Fs= app.FsEditField.Value();
            rp = [app.rp1EditField.Value(),app.rp2EditField.Value(),app.rp3EditField.Value(),app.rp4EditField.Value(),app.rp5EditField_3.Value(),app.rp6EditField.Value(),app.rp7EditField.Value()];
           xr.fijo3 = rfijo(x,rp(3));
            T_V = 30/1000; %tiemnpo de duracion del bloque en ms
         xr.fijobsnr3 = snr_bloque(x,xr.fijo3,T_V,Fs);
            app.UIAxes7.Visible="on";
            plot(app.UIAxes7,xr.fijo3);title(app.UIAxes7,"rp = 3" );
        end

        % Button pushed function: rp4Button
        function rp4ButtonPushed(app, event)
            global x t 
            Fs= app.FsEditField.Value();
            rp = [app.rp1EditField.Value(),app.rp2EditField.Value(),app.rp3EditField.Value(),app.rp4EditField.Value(),app.rp5EditField_3.Value(),app.rp6EditField.Value(),app.rp7EditField.Value()];
           xr.fijo4 = rfijo(x,rp(4));
            T_V = 30/1000; %tiemnpo de duracion del bloque en ms
         xr.fijobsnr4 = snr_bloque(x,xr.fijo4,T_V,Fs);
            app.UIAxes7.Visible="on";
            plot(app.UIAxes7,xr.fijo4);title(app.UIAxes7,"rp = 4" );
        end

        % Button pushed function: rp5Button
        function rp5ButtonPushed(app, event)
            global x t 
            Fs= app.FsEditField.Value();
            rp = [app.rp1EditField.Value(),app.rp2EditField.Value(),app.rp3EditField.Value(),app.rp4EditField.Value(),app.rp5EditField_3.Value(),app.rp6EditField.Value(),app.rp7EditField.Value()];
           xr.fijo5 = rfijo(x,rp(5));
            T_V = 30/1000; %tiemnpo de duracion del bloque en ms
         xr.fijobsnr5 = snr_bloque(x,xr.fijo5,T_V,Fs);
            app.UIAxes7.Visible="on";
            plot(app.UIAxes7,xr.fijo5);title(app.UIAxes7,"rp = 5" );
        end

        % Button pushed function: rp6Button
        function rp6ButtonPushed(app, event)
           global x t 
            Fs= app.FsEditField.Value();
            rp = [app.rp1EditField.Value(),app.rp2EditField.Value(),app.rp3EditField.Value(),app.rp4EditField.Value(),app.rp5EditField_3.Value(),app.rp6EditField.Value(),app.rp7EditField.Value()];
          xr.fijo6 = rfijo(x,rp(6));
            T_V = 30/1000; %tiemnpo de duracion del bloque en ms
         xr.fijobsnr6 = snr_bloque(x,xr.fijo6,T_V,Fs);
            app.UIAxes7.Visible="on";
            plot(app.UIAxes7,xr.fijo6);title(app.UIAxes7,"rp = 6" ); 
        end

        % Button pushed function: rp7Button
        function rp7ButtonPushed(app, event)
            global x t 
            Fs= app.FsEditField.Value();
            rp = [app.rp1EditField.Value(),app.rp2EditField.Value(),app.rp3EditField.Value(),app.rp4EditField.Value(),app.rp5EditField_3.Value(),app.rp6EditField.Value(),app.rp7EditField.Value()];
           xr.fijo7 = rfijo(x,rp(7));
            T_V = 30/1000; %tiemnpo de duracion del bloque en ms
         xr.fijobsnr7 = snr_bloque(x,xr.fijo7,T_V,Fs);
            app.UIAxes7.Visible="on";
            plot(app.UIAxes7,xr.fijo7);title(app.UIAxes7,"rp =7" );
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3_6 = uiaxes(app.UIFigure);
            title(app.UIAxes3_6, 'Grafica')
            xlabel(app.UIAxes3_6, 'X')
            ylabel(app.UIAxes3_6, 'Y')
            zlabel(app.UIAxes3_6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes7_2 = uiaxes(app.UIFigure);
            title(app.UIAxes7_2, 'Title')
            xlabel(app.UIAxes7_2, 'X')
            ylabel(app.UIAxes7_2, 'Y')
            zlabel(app.UIAxes7_2, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.FsEditFieldLabel = uilabel(app.Tab);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.Tab, 'numeric');
            app.FsEditField.Value = 8000;
            app.rEditFieldLabel = uilabel(app.Tab);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.Tab, 'numeric');
            app.rEditField.Value = 24;
            app.ID_ImputEditFieldLabel = uilabel(app.Tab);
            app.ID_ImputEditFieldLabel.Text = 'ID_Imput';
            app.ID_ImputEditField = uieditfield(app.Tab, 'numeric');
            app.ID_ImputEditField.Value = 1;
            app.ID_OutputEditFieldLabel = uilabel(app.Tab);
            app.ID_OutputEditFieldLabel.Text = 'ID_Output';
            app.ID_OutputEditField = uieditfield(app.Tab, 'numeric');
            app.ID_OutputEditField.Value = 3;
            app.microfono = uiimage(app.Tab);
            app.microfono.ImageClickedFcn = createCallbackFcn(app, @microfonoClicked, true);
            app.microfono.ImageSource = fullfile(pathToMLAPP, 'microfono.png');
            app.TcEditField_2Label = uilabel(app.Tab);
            app.TcEditField_2Label.Text = 'Tc';
            app.TcEditField_2 = uieditfield(app.Tab, 'numeric');
            app.TcEditField_2.Value = 6;
            app.rp1EditFieldLabel = uilabel(app.Tab);
            app.rp1EditFieldLabel.Text = 'rp1';
            app.rp1EditField = uieditfield(app.Tab, 'numeric');
            app.rp1EditField.Value = 2;
            app.rp2EditFieldLabel = uilabel(app.Tab);
            app.rp2EditFieldLabel.Text = 'rp2';
            app.rp2EditField = uieditfield(app.Tab, 'numeric');
            app.rp2EditField.Value = 4;
            app.rp3EditFieldLabel = uilabel(app.Tab);
            app.rp3EditFieldLabel.Text = 'rp3';
            app.rp3EditField = uieditfield(app.Tab, 'numeric');
            app.rp3EditField.Value = 8;
            app.rp4EditFieldLabel = uilabel(app.Tab);
            app.rp4EditFieldLabel.Text = 'rp4';
            app.rp4EditField = uieditfield(app.Tab, 'numeric');
            app.rp4EditField.Value = 16;
            app.rp7EditFieldLabel = uilabel(app.Tab);
            app.rp7EditFieldLabel.Text = 'rp7';
            app.rp7EditField = uieditfield(app.Tab, 'numeric');
            app.rp7EditField.Value = 128;
            app.rp6EditFieldLabel = uilabel(app.Tab);
            app.rp6EditFieldLabel.Text = 'rp6';
            app.rp6EditField = uieditfield(app.Tab, 'numeric');
            app.rp6EditField.Value = 64;
            app.rp5EditField_3Label = uilabel(app.Tab);
            app.rp5EditField_3Label.Text = 'rp5';
            app.rp5EditField_3 = uieditfield(app.Tab, 'numeric');
            app.rp5EditField_3.Value = 32;
            app.SealenelTiempoButton = uibutton(app.Tab, 'push');
            app.SealenelTiempoButton.ButtonPushedFcn = createCallbackFcn(app, @SealenelTiempoButtonPushed, true);
            app.SealenelTiempoButton.Text = 'Señal en el Tiempo';
            app.EspectrodeMagnitudButton = uibutton(app.Tab, 'push');
            app.EspectrodeMagnitudButton.ButtonPushedFcn = createCallbackFcn(app, @EspectrodeMagnitudButtonPushed, true);
            app.EspectrodeMagnitudButton.Text = 'Espectro de Magnitud';
            app.EstrogramaButton = uibutton(app.Tab, 'push');
            app.EstrogramaButton.ButtonPushedFcn = createCallbackFcn(app, @EstrogramaButtonPushed, true);
            app.EstrogramaButton.Text = 'Estrograma';
            app.ReproducirButton = uibutton(app.Tab, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Logo_oficial_de_Universitario.png');
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'Tab';
            app.rp1Button = uibutton(app.Tab_2, 'push');
            app.rp1Button.ButtonPushedFcn = createCallbackFcn(app, @rp1ButtonPushed, true);
            app.rp1Button.Text = 'rp1';
            app.rp2Button = uibutton(app.Tab_2, 'push');
            app.rp2Button.ButtonPushedFcn = createCallbackFcn(app, @rp2ButtonPushed, true);
            app.rp2Button.Text = 'rp2';
            app.rp3Button = uibutton(app.Tab_2, 'push');
            app.rp3Button.ButtonPushedFcn = createCallbackFcn(app, @rp3ButtonPushed, true);
            app.rp3Button.Text = 'rp3';
            app.rp4Button = uibutton(app.Tab_2, 'push');
            app.rp4Button.ButtonPushedFcn = createCallbackFcn(app, @rp4ButtonPushed, true);
            app.rp4Button.Text = 'rp4';
            app.rp5Button = uibutton(app.Tab_2, 'push');
            app.rp5Button.ButtonPushedFcn = createCallbackFcn(app, @rp5ButtonPushed, true);
            app.rp5Button.Text = 'rp5';
            app.rp6Button = uibutton(app.Tab_2, 'push');
            app.rp6Button.ButtonPushedFcn = createCallbackFcn(app, @rp6ButtonPushed, true);
            app.rp6Button.Text = 'rp6';
            app.rp7Button = uibutton(app.Tab_2, 'push');
            app.rp7Button.ButtonPushedFcn = createCallbackFcn(app, @rp7ButtonPushed, true);
            app.rp7Button.Text = 'rp7';
            app.TiempoButton = uibutton(app.Tab_2, 'push');
            app.TiempoButton.Text = 'Tiempo';
            app.ReproducirButton_2 = uibutton(app.Tab_2, 'push');
            app.ReproducirButton_2.Text = 'Reproducir ';
            app.SNRTotalButton = uibutton(app.Tab_2, 'push');
            app.SNRTotalButton.Text = 'SNR Total';
            app.SNRBloqueButton = uibutton(app.Tab_2, 'push');
            app.SNRBloqueButton.Text = 'SNR Bloque';
            app.TabGroup2_2 = uitabgroup(app.UIFigure);
            app.Tab_3 = uitab(app.TabGroup2_2);
            app.Tab_3.Title = 'Tab';
            app.rp1Button_2 = uibutton(app.Tab_3, 'push');
            app.rp1Button_2.Text = 'rp1';
            app.rp2Button_2 = uibutton(app.Tab_3, 'push');
            app.rp2Button_2.Text = 'rp2';
            app.rp3Button_2 = uibutton(app.Tab_3, 'push');
            app.rp3Button_2.Text = 'rp3';
            app.rp4Button_2 = uibutton(app.Tab_3, 'push');
            app.rp4Button_2.Text = 'rp4';
            app.rp5Button_2 = uibutton(app.Tab_3, 'push');
            app.rp5Button_2.Text = 'rp5';
            app.rp6Button_2 = uibutton(app.Tab_3, 'push');
            app.rp6Button_2.Text = 'rp6';
            app.rp7Button_2 = uibutton(app.Tab_3, 'push');
            app.rp7Button_2.Text = 'rp7';
            app.TiempoButton_2 = uibutton(app.Tab_3, 'push');
            app.TiempoButton_2.Text = 'Tiempo';
            app.ReproducirButton_3 = uibutton(app.Tab_3, 'push');
            app.ReproducirButton_3.Text = 'Reproducir ';
            app.SNRTotalButton_2 = uibutton(app.Tab_3, 'push');
            app.SNRTotalButton_2.Text = 'SNR Total';
            app.SNRBloqueButton_2 = uibutton(app.Tab_3, 'push');
            app.SNRBloqueButton_2.Text = 'SNR Bloque';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end