classdef app2_REYMUNDO_RENZO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GraficarButton_2      matlab.ui.control.Button
        GraficarButton        matlab.ui.control.Button
        rEditField_2          matlab.ui.control.NumericEditField
        rEditField_2Label     matlab.ui.control.Label
        XmaxEditField_2       matlab.ui.control.NumericEditField
        XmaxEditField_2Label  matlab.ui.control.Label
        XminEditField_2       matlab.ui.control.NumericEditField
        XminEditField_2Label  matlab.ui.control.Label
        rEditField            matlab.ui.control.NumericEditField
        rEditFieldLabel       matlab.ui.control.Label
        XmaxEditField         matlab.ui.control.NumericEditField
        XmaxEditFieldLabel    matlab.ui.control.Label
        XminEditField         matlab.ui.control.NumericEditField
        XminEditFieldLabel    matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: XminEditField
        function XminEditFieldValueChanged(app, event)
        
            
        end

        % Button pushed function: GraficarButton
        function GraficarButtonPushed(app, event)
% Hecho por Renzo Edmundo Reymundo Ramos - U202119710
% Parámetros 
r = app.rEditField.Value;                     % Número de bits
L = 2^r;                   % Niveles de cuantización
xmin = app.XminEditField.Value;                 % Valor mínimo de entrada
xmax = app.XmaxEditField.Value;                  % Valor máximo de entrada
Delta = (xmax - xmin)/L;   % Paso de cuantización (Δ)

% Señal de entrada
x = linspace(xmin-0.2, xmax+0.2, 1000);  % Señal de entrada

% Cuantización tipo Mid-Rise
x_q = floor((x - xmin)/Delta) * Delta + xmin + Delta/2;

% Limitar valores fuera de rango (clipping)
x_q(x > xmax) = xmax - Delta/2;
x_q(x < xmin) = xmin + Delta/2;

% Grafico tipo Mid-Rise
stairs(app.UIAxes2, x, x_q, 'LineWidth', 2);
xlabel(app.UIAxes2, 'Entrada x');
ylabel(app.UIAxes2, 'Salida cuantizada Q(x)');
title(app.UIAxes2, ['Cuantizador Mid-Rise (r = ', num2str(r), ' bits)']);
grid(app.UIAxes2, 'on');

% Ejes centrados
hold(app.UIAxes2, 'on');
plot(app.UIAxes2, [xmin-0.2 xmax+0.2], [0 0], 'k--'); % eje X
plot(app.UIAxes2, [0 0], [min(x_q)-0.1 max(x_q)+0.1], 'k--'); % eje Y
hold(app.UIAxes2, 'off');

        end

        % Button pushed function: GraficarButton_2
        function GraficarButton_2Pushed(app, event)
        % Hecho por Renzo Edmundo Reymundo Ramos - U202119710
        % Parámetros 
        r = app.rEditField_2.Value;                    % Número de bits
        L = 2^r;                                       % Niveles de cuantización
        xmin = app.XminEditField_2.Value;             % Valor mínimo de entrada
        xmax = app.XmaxEditField_2.Value;             % Valor máximo de entrada
        Delta = (xmax - xmin)/L;                      % Paso de cuantización (Δ)
        
        % Señal de entrada adaptativa
        x = linspace(xmin - 0.2, xmax + 0.2, 1000);    % Señal de entrada
        
        % Cuantización tipo Mid-Tread
        x_q = round((x-xmin) / Delta) * Delta+xmin;
        
        % Limitar valores fuera de rango (clipping)
        x_q(x > xmax) = xmax;
        x_q(x < xmin) = xmin;
        
        % Graficar cuantización
        stairs(app.UIAxes, x, x_q, 'LineWidth', 2);
        xlabel(app.UIAxes, 'Entrada x');
        ylabel(app.UIAxes, 'Salida cuantizada Q(x)');
        title(app.UIAxes, ['Cuantizador Mid-Tread (r = ', num2str(r), ' bits)']);
        grid(app.UIAxes, 'on');
        
        % Ejes centrados
        hold(app.UIAxes, 'on');
        plot(app.UIAxes, [xmin-0.2 xmax+0.2], [0 0], 'k--'); % Eje X
        plot(app.UIAxes, [0 0], [min(x_q)-0.1 max(x_q)+0.1], 'k--'); % Eje Y
        
        % Ajuste de límites para mejor visualización
        xlim(app.UIAxes, [xmin-0.5 xmax+0.5]);
        ylim(app.UIAxes, [min(x_q)-0.5 max(x_q)+0.5]);
        hold(app.UIAxes, 'off');

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.XminEditFieldLabel = uilabel(app.UIFigure);
            app.XminEditFieldLabel.Text = 'Xmin';
            app.XminEditField = uieditfield(app.UIFigure, 'numeric');
            app.XminEditField.ValueChangedFcn = createCallbackFcn(app, @XminEditFieldValueChanged, true);
            app.XmaxEditFieldLabel = uilabel(app.UIFigure);
            app.XmaxEditFieldLabel.Text = 'Xmax';
            app.XmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.UIFigure, 'numeric');
            app.XminEditField_2Label = uilabel(app.UIFigure);
            app.XminEditField_2Label.Text = 'Xmin';
            app.XminEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XmaxEditField_2Label = uilabel(app.UIFigure);
            app.XmaxEditField_2Label.Text = 'Xmax';
            app.XmaxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.rEditField_2Label = uilabel(app.UIFigure);
            app.rEditField_2Label.Text = 'r';
            app.rEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.GraficarButton = uibutton(app.UIFigure, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarButtonPushed, true);
            app.GraficarButton.Text = 'Graficar';
            app.GraficarButton_2 = uibutton(app.UIFigure, 'push');
            app.GraficarButton_2.ButtonPushedFcn = createCallbackFcn(app, @GraficarButton_2Pushed, true);
            app.GraficarButton_2.Text = 'Graficar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2_REYMUNDO_RENZO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end