classdef RENZO_FIR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        VentanaButtonGroup   matlab.ui.container.ButtonGroup
        BlackmanButton       matlab.ui.control.RadioButton
        HammingButton        matlab.ui.control.RadioButton
        HanningButton        matlab.ui.control.RadioButton
        BartlettButton       matlab.ui.control.RadioButton
        RectangularButton    matlab.ui.control.RadioButton
        TiposButtonGroup     matlab.ui.container.ButtonGroup
        PasabandasButton2    matlab.ui.control.RadioButton
        PasabandasButton     matlab.ui.control.RadioButton
        PasaaltosButton      matlab.ui.control.RadioButton
        PasabajosButton      matlab.ui.control.RadioButton
        TabGroup             matlab.ui.container.TabGroup
        ParametrosTab        matlab.ui.container.Tab
        GenerarFiltroButton  matlab.ui.control.Button
        THEditField          matlab.ui.control.NumericEditField
        THEditFieldLabel     matlab.ui.control.Label
        TLEditField          matlab.ui.control.NumericEditField
        TLEditFieldLabel     matlab.ui.control.Label
        OrdenEditField       matlab.ui.control.NumericEditField
        OrdenEditFieldLabel  matlab.ui.control.Label
        UIAxes8              matlab.ui.control.UIAxes
        UIAxes7              matlab.ui.control.UIAxes
        UIAxes6              matlab.ui.control.UIAxes
        UIAxes5              matlab.ui.control.UIAxes
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarFiltroButton
        function GenerarFiltroButtonPushed(app, event)
 % 1. Leer y validar entradas del usuario
    N = app.OrdenEditField.Value;
    TL = app.TLEditField.Value;
    TH = app.THEditField.Value;
    tipo = app.TiposButtonGroup.SelectedObject.Text;
    ventana = app.VentanaButtonGroup.SelectedObject.Text;
    disp(N);

    % Validación básica
    if isnan(N) || N <= 0
        uialert(app.UIFigure, 'El orden debe ser un número entero positivo.', 'Error');
        return;
    end
    if any(isnan([TL, TH])) || any([TL, TH] < 0) || any([TL, TH] > 0.5)
        uialert(app.UIFigure, 'Las frecuencias deben estar entre 0 y 0.5 (normalizadas).', 'Error');
        return;
    end
    if strcmp(tipo, 'Pasa bandas') || strcmp(tipo, 'Rechaza banda')
        if TL >= TH
            uialert(app.UIFigure, 'En filtros pasa/rechaza banda, tl debe ser menor que th.', 'Error');
            return;
        end
    end

    % 2. Normalizar frecuencias
    fs = 1;
    Wn = [TL TH] / (fs/2);

    % 3. Diseñar filtro FIR ideal
    switch tipo
        case 'Pasa bajos'
            h = fir1(N, TL/(fs/2), 'low');
        case 'Pasa altos'
            h = fir1(N, TH/(fs/2), 'high');
        case 'Pasa bandas'
            h = fir1(N, Wn, 'bandpass');
        case 'Rechaza banda'
            h = fir1(N, Wn, 'stop');
    end

    % 4. Crear ventana
    switch ventana
        case 'Rectangular', win = rectwin(N+1);
        case 'Bartlett',    win = bartlett(N+1);
        case 'Hanning',     win = hanning(N+1);
        case 'Hamming',     win = hamming(N+1);
        case 'Blackman',    win = blackman(N+1);
    end

    % 5. Aplicar ventana
    h = h(:);           % asegura vector columna
    win = win(:);       % asegura vector columna
    h_win = h .* win;   % aplicación de ventana

    % 6. Mostrar gráficas

    % Axes 1: Impulso sin aventanear
    stem(app.UIAxes3, h, 'filled'); 
    grid(app.UIAxes3, 'on');
    title(app.UIAxes3, 'Respuesta al impulso sin aventanear');
    xlabel(app.UIAxes3, '# de muestra (rad)'); ylabel(app.UIAxes3, 'Amplitud');
    ylim(app.UIAxes3, [min(h)-0.1, max(h)+0.1]);
    
    % Axes 2: Ventana
    plot(app.UIAxes6, win); 
    grid(app.UIAxes6, 'on');
    title(app.UIAxes6, 'Ventana seleccionada');
    xlabel(app.UIAxes6, '# de muestra (rad)'); ylabel(app.UIAxes6, 'Amplitud');
    ylim(app.UIAxes6, [0 1.1]);
    
    % Axes 3: Impulso aventaneado
    stem(app.UIAxes8, 0:N, h_win, 'filled'); 
    grid(app.UIAxes8, 'on');
    title(app.UIAxes8, 'Respuesta al impulso aventaneada');
    xlabel(app.UIAxes8, '# de muestra (rad)'); ylabel(app.UIAxes8, 'Amplitud');
    ylim(app.UIAxes8, [min(h_win)-0.1, max(h_win)+0.1]);
    
    % Axes 4: Espectro sin ventana
    [H, w] = freqz(h, 1, 1024);
    plot(app.UIAxes2, w/pi, abs(H)); 
    grid(app.UIAxes2, 'on');
    title(app.UIAxes2, 'Espectro de magnitud sin aventanear');
    xlabel(app.UIAxes2, 'Frecuencia (rad)'); ylabel(app.UIAxes2, '|H(e^{j\omega})|');
    ylim(app.UIAxes2, [0 1.1]);
    
    % Axes 5: Espectro de la ventana
    W = fftshift(abs(fft(win, 1024)));
    f = linspace(-1, 1, length(W));
    plot(app.UIAxes5, f, W); 
    grid(app.UIAxes5, 'on');
    title(app.UIAxes5, 'Espectro de la ventana');
    xlabel(app.UIAxes5, 'Frecuencia (rad)'); ylabel(app.UIAxes5, 'Magnitud');
    
    % Axes 6: Espectro FIR aventaneado
    [Hw, w] = freqz(h_win, 1, 1024);
    plot(app.UIAxes7, w/pi, abs(Hw)); 
    grid(app.UIAxes7, 'on');
    title(app.UIAxes7, 'Magnitud de la respuesta aventaneada (espectro FIR)');
    xlabel(app.UIAxes7, 'Frecuencia (rad)'); ylabel(app.UIAxes7, '|H(e^{j\omega})|');
    ylim(app.UIAxes7, [0 1.1]);
    
    % Axes 7: Polos y ceros
    zplane(h_win, 1, app.UIAxes);
    title(app.UIAxes, 'Diagrama de Polos y Ceros');
    
    % Axes 8: Fase
    plot(app.UIAxes4, w/pi, unwrap(angle(Hw))); 
    grid(app.UIAxes4, 'on');
    title(app.UIAxes4, 'Fase de la respuesta aventaneada');
    xlabel(app.UIAxes4, 'Frecuencia (rad)'); ylabel(app.UIAxes4, 'Fase (rad)');
    ylim(app.UIAxes4, [-pi pi]);              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametrosTab = uitab(app.TabGroup);
            app.ParametrosTab.Title = 'Parametros';
            app.OrdenEditFieldLabel = uilabel(app.ParametrosTab);
            app.OrdenEditFieldLabel.Text = 'Orden';
            app.OrdenEditField = uieditfield(app.ParametrosTab, 'numeric');
            app.TLEditFieldLabel = uilabel(app.ParametrosTab);
            app.TLEditFieldLabel.Text = 'TL';
            app.TLEditField = uieditfield(app.ParametrosTab, 'numeric');
            app.THEditFieldLabel = uilabel(app.ParametrosTab);
            app.THEditFieldLabel.Text = 'TH';
            app.THEditField = uieditfield(app.ParametrosTab, 'numeric');
            app.GenerarFiltroButton = uibutton(app.ParametrosTab, 'push');
            app.GenerarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarFiltroButtonPushed, true);
            app.GenerarFiltroButton.Text = 'Generar Filtro';
            app.TiposButtonGroup = uibuttongroup(app.UIFigure);
            app.TiposButtonGroup.Title = 'Tipos';
            app.PasabajosButton = uiradiobutton(app.TiposButtonGroup);
            app.PasabajosButton.Text = 'Pasa bajos';
            app.PasabajosButton.Value = true;
            app.PasaaltosButton = uiradiobutton(app.TiposButtonGroup);
            app.PasaaltosButton.Text = 'Pasa altos';
            app.PasabandasButton = uiradiobutton(app.TiposButtonGroup);
            app.PasabandasButton.Text = 'Pasa bandas';
            app.PasabandasButton2 = uiradiobutton(app.TiposButtonGroup);
            app.PasabandasButton2.Text = 'Rechaza banda';
            app.VentanaButtonGroup = uibuttongroup(app.UIFigure);
            app.VentanaButtonGroup.Title = 'Ventana';
            app.RectangularButton = uiradiobutton(app.VentanaButtonGroup);
            app.RectangularButton.Text = 'Rectangular';
            app.RectangularButton.Value = true;
            app.BartlettButton = uiradiobutton(app.VentanaButtonGroup);
            app.BartlettButton.Text = 'Bartlett';
            app.HanningButton = uiradiobutton(app.VentanaButtonGroup);
            app.HanningButton.Text = 'Hanning';
            app.HammingButton = uiradiobutton(app.VentanaButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.BlackmanButton = uiradiobutton(app.VentanaButtonGroup);
            app.BlackmanButton.Text = 'Blackman';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RENZO_FIR

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end