classdef IIR_RENZO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DiagramadepolosycerosLabel  matlab.ui.control.Label
        EspectrodeFaseLabel         matlab.ui.control.Label
        EspectrodeMagnitudLabel     matlab.ui.control.Label
        TiempoLabel                 matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        ParametrosTab               matlab.ui.container.Tab
        TLEditField                 matlab.ui.control.NumericEditField
        TLEditFieldLabel            matlab.ui.control.Label
        THEditField                 matlab.ui.control.NumericEditField
        THEditFieldLabel            matlab.ui.control.Label
        OrdenEditField              matlab.ui.control.NumericEditField
        OrdenEditFieldLabel         matlab.ui.control.Label
        GenerarButton               matlab.ui.control.Button
        UIAxes16                    matlab.ui.control.UIAxes
        UIAxes15                    matlab.ui.control.UIAxes
        UIAxes14                    matlab.ui.control.UIAxes
        UIAxes13                    matlab.ui.control.UIAxes
        UIAxes12                    matlab.ui.control.UIAxes
        UIAxes11                    matlab.ui.control.UIAxes
        UIAxes10                    matlab.ui.control.UIAxes
        UIAxes9                     matlab.ui.control.UIAxes
        UIAxes8                     matlab.ui.control.UIAxes
        UIAxes7                     matlab.ui.control.UIAxes
        UIAxes6                     matlab.ui.control.UIAxes
        UIAxes5                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)

    % Leer valores numéricos directamente
    N  = app.OrdenEditField.Value;
    TL = app.TLEditField.Value;
    TH = app.THEditField.Value;

    % Validaciones
    if N <= 0
        uialert(app.UIFigure, 'El orden debe ser un número entero positivo.', 'Error');
        return;
    end
    if TL <= 0 || TH <= 0 || TL >= pi || TH >= pi
        uialert(app.UIFigure, 'TL y TH deben estar entre 0 y π.', 'Error');
        return;
    end
    if TL >= TH
        uialert(app.UIFigure, 'TL debe ser menor que TH.', 'Error');
        return;
    end

    % Normalizar
    TL_n = TL / pi;
    TH_n = TH / pi;

    % Diseñar filtros
    [b1, a1] = butter(N, TL_n, 'low');        % Pasabajas
    [b2, a2] = butter(N, TH_n, 'high');       % Pasaaltas
    [b3, a3] = butter(N, [TL_n TH_n], 'bandpass'); % Pasabanda
    [b4, a4] = butter(N, [TL_n TH_n], 'stop');     % Rechazabanda

    % --- Pasabajas ---
    h = impz(b1, a1, 100);
    [H, w] = freqz(b1, a1, 1024);
    
    stem(app.UIAxes, h, 'filled');
    title(app.UIAxes, 'Impulso - Pasabajas', 'Color', 'w'); 
    grid(app.UIAxes, 'on');
    
    plot(app.UIAxes2, w, abs(H)); 
    title(app.UIAxes2, 'Magnitud - Pasabajas', 'Color', 'w'); 
    grid(app.UIAxes2, 'on');
    
    plot(app.UIAxes3, w, unwrap(angle(H)));
    title(app.UIAxes3, 'Fase - Pasabajas', 'Color', 'w'); 
    grid(app.UIAxes3, 'on');
    
    cla(app.UIAxes4); 
    zplane(b1, a1, app.UIAxes4);
    title(app.UIAxes4, 'Polos y Ceros - Pasabajas', 'Color', 'w');


        % --- Pasaaltas ---
    h = impz(b2, a2, 100);
    [H, w] = freqz(b2, a2, 1024);
    
    stem(app.UIAxes5, h, 'filled');
    title(app.UIAxes5, 'Impulso - Pasaaltas', 'Color', 'w'); 
    grid(app.UIAxes5, 'on');
    
    plot(app.UIAxes6, w, abs(H)); 
    title(app.UIAxes6, 'Magnitud - Pasaaltas', 'Color', 'w'); 
    grid(app.UIAxes6, 'on');
    
    plot(app.UIAxes7, w, unwrap(angle(H)));
    title(app.UIAxes7, 'Fase - Pasaaltas', 'Color', 'w'); 
    grid(app.UIAxes7, 'on');
    
    cla(app.UIAxes8); 
    zplane(b2, a2, app.UIAxes8);
    title(app.UIAxes8, 'Polos y Ceros - Pasaaltas', 'Color', 'w');
    
    % --- Pasabanda ---
    h = impz(b3, a3, 100);
    [H, w] = freqz(b3, a3, 1024);
    
    stem(app.UIAxes9, h, 'filled');
    title(app.UIAxes9, 'Impulso - Pasabanda', 'Color', 'w'); 
    grid(app.UIAxes9, 'on');
    
    plot(app.UIAxes10, w, abs(H)); 
    title(app.UIAxes10, 'Magnitud - Pasabanda', 'Color', 'w'); 
    grid(app.UIAxes10, 'on');
    
    plot(app.UIAxes11, w, unwrap(angle(H)));
    title(app.UIAxes11, 'Fase - Pasabanda', 'Color', 'w'); 
    grid(app.UIAxes11, 'on');
    
    cla(app.UIAxes12); 
    zplane(b3, a3, app.UIAxes12);
    title(app.UIAxes12, 'Polos y Ceros - Pasabanda', 'Color', 'w');
    
    % --- Rechazabanda ---
    h = impz(b4, a4, 100);
    [H, w] = freqz(b4, a4, 1024);
    
    stem(app.UIAxes13, h, 'filled');
    title(app.UIAxes13, 'Impulso - Rechazabanda', 'Color', 'w'); 
    grid(app.UIAxes13, 'on');
    
    plot(app.UIAxes14, w, abs(H)); 
    title(app.UIAxes14, 'Magnitud - Rechazabanda', 'Color', 'w'); 
    grid(app.UIAxes14, 'on');
    
    plot(app.UIAxes15, w, unwrap(angle(H)));
    title(app.UIAxes15, 'Fase - Rechazabanda', 'Color', 'w'); 
    grid(app.UIAxes15, 'on');
    
    cla(app.UIAxes16); 
    zplane(b4, a4, app.UIAxes16);
    title(app.UIAxes16, 'Polos y Ceros - Rechazabanda', 'Color', 'w');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pasabajas')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Pasabajas')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Pasabajas')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Pasabajas')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'PasaAltas')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'PasaAltas')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'PasaAltas')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'PasaAltas')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.UIFigure);
            title(app.UIAxes9, 'PasaBanda')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.UIFigure);
            title(app.UIAxes10, 'PasaBanda')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes11 = uiaxes(app.UIFigure);
            title(app.UIAxes11, 'PasaBanda')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.UIAxes12 = uiaxes(app.UIFigure);
            title(app.UIAxes12, 'PasaBanda')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            app.UIAxes13 = uiaxes(app.UIFigure);
            title(app.UIAxes13, 'RechazaBanda')
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            zlabel(app.UIAxes13, 'Z')
            app.UIAxes14 = uiaxes(app.UIFigure);
            title(app.UIAxes14, 'RechazaBanda')
            xlabel(app.UIAxes14, 'X')
            ylabel(app.UIAxes14, 'Y')
            zlabel(app.UIAxes14, 'Z')
            app.UIAxes15 = uiaxes(app.UIFigure);
            title(app.UIAxes15, 'RechazaBanda')
            xlabel(app.UIAxes15, 'X')
            ylabel(app.UIAxes15, 'Y')
            zlabel(app.UIAxes15, 'Z')
            app.UIAxes16 = uiaxes(app.UIFigure);
            title(app.UIAxes16, 'RechazaBanda')
            xlabel(app.UIAxes16, 'X')
            ylabel(app.UIAxes16, 'Y')
            zlabel(app.UIAxes16, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParametrosTab = uitab(app.TabGroup);
            app.ParametrosTab.Title = 'Parametros';
            app.GenerarButton = uibutton(app.ParametrosTab, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.OrdenEditFieldLabel = uilabel(app.ParametrosTab);
            app.OrdenEditFieldLabel.Text = 'Orden';
            app.OrdenEditField = uieditfield(app.ParametrosTab, 'numeric');
            app.OrdenEditField.Value = 2;
            app.THEditFieldLabel = uilabel(app.ParametrosTab);
            app.THEditFieldLabel.Text = 'TH';
            app.THEditField = uieditfield(app.ParametrosTab, 'numeric');
            app.THEditField.Value = 2.75;
            app.TLEditFieldLabel = uilabel(app.ParametrosTab);
            app.TLEditFieldLabel.Text = 'TL';
            app.TLEditField = uieditfield(app.ParametrosTab, 'numeric');
            app.TLEditField.Value = 0.25;
            app.TiempoLabel = uilabel(app.UIFigure);
            app.TiempoLabel.Text = 'Tiempo';
            app.EspectrodeMagnitudLabel = uilabel(app.UIFigure);
            app.EspectrodeMagnitudLabel.Text = 'Espectro de Magnitud';
            app.EspectrodeFaseLabel = uilabel(app.UIFigure);
            app.EspectrodeFaseLabel.Text = 'Espectro de Fase';
            app.DiagramadepolosycerosLabel = uilabel(app.UIFigure);
            app.DiagramadepolosycerosLabel.Text = 'Diagrama de polos y ceros';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IIR_RENZO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end