classdef EfectoMusical_Renzo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlayButton_4                matlab.ui.control.Button
        PlayButton_3                matlab.ui.control.Button
        PlayButton_2                matlab.ui.control.Button
        PlayButton                  matlab.ui.control.Button
        ProcesarenFrecuenciaButton  matlab.ui.control.Button
        ProcesarenTiempoButton      matlab.ui.control.Button
        CargarDropDown              matlab.ui.control.DropDown
        CargarDropDownLabel         matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        EFECTOSTab                  matlab.ui.container.Tab
        Efecto4Label                matlab.ui.control.Label
        Efecto3Label                matlab.ui.control.Label
        Efecto2Label                matlab.ui.control.Label
        Efecto1Label                matlab.ui.control.Label
        ReverbButton                matlab.ui.control.Button
        DelayButton                 matlab.ui.control.Button
        CombFilterButton            matlab.ui.control.Button
        STOPButton                  matlab.ui.control.Button
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    y_original
    fs
    y_comb
    y_delay
    y_reverb
    player
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CargarDropDown
        function CargarDropDownValueChanged(app, event)
        value = app.CargarDropDown.Value;
        switch value
            case 'MUSICA1'
                file = 'MUSICA1.wav';
            case 'MUSICA2'
                file = 'MUSICA2.wav'; 
            case 'MUSICA3'
                file = 'MUSICA3.wav'; 
            case 'MUSICA4'
                file = 'MUSICA4.wav'; 
            case 'MUSICA5'
                file = 'MUSICA5.wav'; 
            otherwise
                uialert(app.UIFigure, 'Opción no válida.', 'Error');
                return;
        end
    try
        [y, fs] = audioread(file);
    catch
        uialert(app.UIFigure, 'No se pudo cargar el archivo.', 'Error');
        return;
    end

    y = y(:,1);                        
    y = y(1:min(end, fs*50));          
    app.y_original = y;
    app.fs = fs(1);                    

    t = (0:length(y)-1)/app.fs;
    plot(app.UIAxes, t, y);
    grid(app.UIAxes, 'on'); 
    title(app.UIAxes, 'Original - Tiempo');
        

        end

        % Button pushed function: CombFilterButton
        function CombFilterButtonPushed(app, event)
        y = app.y_original;
        fs = app.fs;  
    
        d = round(0.03 * fs); % 30 ms delay
        b = [1 zeros(1, d-1) 0.7];
        a = 1;
        y_comb = filter(b, a, y);
    
        app.y_comb = y_comb;
        t = (0:length(y_comb)-1)/fs;
        plot(app.UIAxes2, t, y_comb);
        grid(app.UIAxes2, 'on');
        title(app.UIAxes2, 'Comb Filter - Tiempo');

         
        end

        % Button pushed function: DelayButton
        function DelayButtonPushed(app, event)
        y = app.y_original;
        fs = app.fs;
    
        d = round(0.1 * fs); 
        y_delay = y;
        y_delay(d+1:end) = y_delay(d+1:end) + 0.5 * y(1:end-d);
    
        app.y_delay = y_delay;
        t = (0:length(y_delay)-1)/fs;
        plot(app.UIAxes3, t, y_delay);
        grid(app.UIAxes3, 'on');
        title(app.UIAxes3, 'Delay - Tiempo');

        end

        % Button pushed function: ReverbButton
        function ReverbButtonPushed(app, event)
        y = app.y_original(:);
        fs = app.fs;
    
        h = zeros(1, round(0.4 * fs)); 
        h(1) = 1;
        h(end) = 0.4;
    
        y_reverb = conv(y, h, 'same');
    
        app.y_reverb = y_reverb;
        t = (0:length(y_reverb)-1)/fs;
        plot(app.UIAxes4, t, y_reverb);
        grid(app.UIAxes4, 'on'); 
        title(app.UIAxes4, 'Reverb - Tiempo'); 

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
        if isempty(app.y_original) || isempty(app.fs)
            uialert(app.UIFigure, 'Primero selecciona una música válida.', 'Advertencia');
            return;
        end

        fs = app.fs;
        if numel(fs) > 1
            fs = fs(1);
        end

        app.player = audioplayer(app.y_original, fs);
        play(app.player);
     
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
        if isempty(app.y_comb) || isempty(app.fs)
            uialert(app.UIFigure, 'Aplica el efecto Comb filter antes de reproducir.', 'Advertencia');
            return;
        end

        fs = app.fs;
        if numel(fs) > 1
            fs = fs(1);
        end

        app.player = audioplayer(app.y_comb, fs);
        play(app.player);
  
        end

        % Button pushed function: PlayButton_3
        function PlayButton_3Pushed(app, event)
        if isempty(app.y_delay) || isempty(app.fs)
            uialert(app.UIFigure, 'Aplica el efecto Delay antes de reproducir.', 'Advertencia');
            return;
        end

        fs = app.fs;
        if numel(fs) > 1
            fs = fs(1);
        end

        app.player = audioplayer(app.y_delay, fs);
        play(app.player);   
        end

        % Button pushed function: PlayButton_4
        function PlayButton_4Pushed(app, event)
        if isempty(app.y_reverb) || isempty(app.fs)
            uialert(app.UIFigure, 'Aplica el efecto Reverb antes de reproducir.', 'Advertencia');
            return;
        end

        fs = app.fs;
        if numel(fs) > 1
            fs = fs(1);
        end
    
        app.player = audioplayer(app.y_reverb, fs);
        play(app.player);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
        if ~isempty(app.player) && isplaying(app.player)
            pause(app.player);
        end
        end

        % Button pushed function: ProcesarenFrecuenciaButton
        function ProcesarenFrecuenciaButtonPushed(app, event)
fs = app.fs;

    % === FUNCION AUXILIAR PARA GRAFICAR EN ESCALA LOG ===
    function plotFreqLog(y, ax, titulo)
        if isempty(y)
            cla(ax); % limpia el eje si no hay señal
            return;
        end

        N = length(y);
        Y = abs(fft(y));
        Y = Y / max(Y); % normaliza magnitud
        f = linspace(0, fs/2, floor(N/2));

        plot(ax, f, Y(1:floor(N/2)));
        title(ax, titulo);
        xlabel(ax, 'Frecuencia (Hz)');
        ylabel(ax, 'Magnitud Normalizada');
        grid(ax, 'on');
        set(ax, 'XScale', 'log');      % Escala logarítmica
        xlim(ax, [20 5000]);           % Rango audible típico
    end

    % === LLAMADAS A LA FUNCIÓN PARA CADA SEÑAL ===
    plotFreqLog(app.y_original, app.UIAxes,  'Original - Frecuencia');
    plotFreqLog(app.y_comb,     app.UIAxes2, 'Comb Filter - Frecuencia');
    plotFreqLog(app.y_delay,    app.UIAxes3, 'Delay - Frecuencia');
    plotFreqLog(app.y_reverb,   app.UIAxes4, 'Reverb - Frecuencia');
     
        end

        % Button pushed function: ProcesarenTiempoButton
        function ProcesarenTiempoButtonPushed(app, event)
    % Borra efectos
    app.y_comb = [];
    app.y_delay = [];
    app.y_reverb = [];

    % Señal original
    y = app.y_original;
    fs = app.fs;
    t = (0:length(y)-1)/fs;

    % ==== REINICIAR Y GRAFICAR CADA EJE ====
    % Original
    cla(app.UIAxes);
    set(app.UIAxes, 'XScale', 'linear', 'YScale', 'linear');
    xlim(app.UIAxes, 'auto'); ylim(app.UIAxes, 'auto');
    plot(app.UIAxes, t, y);
    title(app.UIAxes, 'Original - Tiempo');
    xlabel(app.UIAxes, 'Tiempo (s)'); ylabel(app.UIAxes, 'Amplitud');
    grid(app.UIAxes, 'on');

    % Comb
    cla(app.UIAxes2);
    set(app.UIAxes2, 'XScale', 'linear', 'YScale', 'linear');
    xlim(app.UIAxes2, 'auto'); ylim(app.UIAxes2, 'auto');
    title(app.UIAxes2, 'Comb Filter - Tiempo');
    xlabel(app.UIAxes2, 'Tiempo (s)'); ylabel(app.UIAxes2, 'Amplitud');
    grid(app.UIAxes2, 'on');

    % Delay
    cla(app.UIAxes3);
    set(app.UIAxes3, 'XScale', 'linear', 'YScale', 'linear');
    xlim(app.UIAxes3, 'auto'); ylim(app.UIAxes3, 'auto');
    title(app.UIAxes3, 'Delay - Tiempo');
    xlabel(app.UIAxes3, 'Tiempo (s)'); ylabel(app.UIAxes3, 'Amplitud');
    grid(app.UIAxes3, 'on');

    % Reverb
    cla(app.UIAxes4);
    set(app.UIAxes4, 'XScale', 'linear', 'YScale', 'linear');
    xlim(app.UIAxes4, 'auto'); ylim(app.UIAxes4, 'auto');
    title(app.UIAxes4, 'Reverb - Tiempo');
    xlabel(app.UIAxes4, 'Tiempo (s)'); ylabel(app.UIAxes4, 'Amplitud');
    grid(app.UIAxes4, 'on');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EFECTOSTab = uitab(app.TabGroup);
            app.EFECTOSTab.Title = 'EFECTOS';
            app.STOPButton = uibutton(app.EFECTOSTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.CombFilterButton = uibutton(app.EFECTOSTab, 'push');
            app.CombFilterButton.ButtonPushedFcn = createCallbackFcn(app, @CombFilterButtonPushed, true);
            app.CombFilterButton.Text = 'Comb Filter';
            app.DelayButton = uibutton(app.EFECTOSTab, 'push');
            app.DelayButton.ButtonPushedFcn = createCallbackFcn(app, @DelayButtonPushed, true);
            app.DelayButton.Text = 'Delay';
            app.ReverbButton = uibutton(app.EFECTOSTab, 'push');
            app.ReverbButton.ButtonPushedFcn = createCallbackFcn(app, @ReverbButtonPushed, true);
            app.ReverbButton.Text = 'Reverb';
            app.Efecto1Label = uilabel(app.EFECTOSTab);
            app.Efecto1Label.Text = 'Efecto 1';
            app.Efecto2Label = uilabel(app.EFECTOSTab);
            app.Efecto2Label.Text = 'Efecto 2';
            app.Efecto3Label = uilabel(app.EFECTOSTab);
            app.Efecto3Label.Text = 'Efecto 3';
            app.Efecto4Label = uilabel(app.EFECTOSTab);
            app.Efecto4Label.Text = 'Efecto 4';
            app.CargarDropDownLabel = uilabel(app.UIFigure);
            app.CargarDropDownLabel.Text = 'Cargar';
            app.CargarDropDown = uidropdown(app.UIFigure);
            app.CargarDropDown.Items = {'MUSICA1', 'MUSICA2', 'MUSICA3', 'MUSICA4', 'MUSICA5'};
            app.CargarDropDown.ValueChangedFcn = createCallbackFcn(app, @CargarDropDownValueChanged, true);
            app.CargarDropDown.Value = 'MUSICA1';
            app.ProcesarenTiempoButton = uibutton(app.UIFigure, 'push');
            app.ProcesarenTiempoButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarenTiempoButtonPushed, true);
            app.ProcesarenTiempoButton.Text = 'Procesar en Tiempo';
            app.ProcesarenFrecuenciaButton = uibutton(app.UIFigure, 'push');
            app.ProcesarenFrecuenciaButton.ButtonPushedFcn = createCallbackFcn(app, @ProcesarenFrecuenciaButtonPushed, true);
            app.ProcesarenFrecuenciaButton.Text = 'Procesar en Frecuencia';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PlayButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.PlayButton_3 = uibutton(app.UIFigure, 'push');
            app.PlayButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_3Pushed, true);
            app.PlayButton_3.Text = 'Play';
            app.PlayButton_4 = uibutton(app.UIFigure, 'push');
            app.PlayButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_4Pushed, true);
            app.PlayButton_4.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EfectoMusical_Renzo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end