classdef tareita2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        EFECTOSTab          matlab.ui.container.Tab
        Image               matlab.ui.control.Image
        DropDown            matlab.ui.control.DropDown
        Label               matlab.ui.control.Label
        ReverbButton        matlab.ui.control.Button
        DelayButton         matlab.ui.control.Button
        CombfilterButton    matlab.ui.control.Button
        ReproducirButton_4  matlab.ui.control.Button
        ReproducirButton_3  matlab.ui.control.Button
        ReproducirButton_2  matlab.ui.control.Button
        ReproducirButton    matlab.ui.control.Button
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    properties (Access = private)
    y_original
    fs
    y_comb
    y_delay
    y_reverb
    player
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
value = app.DropDown.Value;


    switch value
        case 'Musica 1'
            file = 'RHCP49s.mp3';
        case 'Musica 2'
            file = 'sALgOpALacALLe.mp3'; 
        otherwise
            uialert(app.UIFigure, 'Opción no válida.', 'Error');
            return;
    end

    try
        [y, fs] = audioread(file);
    catch
        uialert(app.UIFigure, 'No se pudo cargar el archivo.', 'Error');
        return;
    end

    y = y(:,1);                        
    y = y(1:min(end, fs*50));          
    app.y_original = y;
    app.fs = fs(1);                    

    t = (0:length(y)-1)/app.fs;
    plot(app.UIAxes, t, y);
    grid(app.UIAxes, 'on'); 
    title(app.UIAxes, 'Original - Tiempo');
        end

        % Button pushed function: CombfilterButton
        function CombfilterButtonPushed(app, event)
    y = app.y_original;
    fs = app.fs;  

    d = round(0.03 * fs); % 30 ms delay
    b = [1 zeros(1, d-1) 0.7];
    a = 1;
    y_comb = filter(b, a, y);

    app.y_comb = y_comb;
    t = (0:length(y_comb)-1)/fs;
    plot(app.UIAxes2, t, y_comb);
    grid(app.UIAxes2, 'on');
    title(app.UIAxes2, 'Comb Filter - Tiempo');
        end

        % Button pushed function: DelayButton
        function DelayButtonPushed(app, event)
    y = app.y_original;
    fs = app.fs;

    d = round(0.1 * fs); 
    y_delay = y;
    y_delay(d+1:end) = y_delay(d+1:end) + 0.5 * y(1:end-d);

    app.y_delay = y_delay;
    t = (0:length(y_delay)-1)/fs;
    plot(app.UIAxes3, t, y_delay);
    grid(app.UIAxes3, 'on');
    title(app.UIAxes3, 'Delay - Tiempo');
        end

        % Button pushed function: ReverbButton
        function ReverbButtonPushed(app, event)
    y = app.y_original(:);
    fs = app.fs;

    h = zeros(1, round(0.4 * fs)); 
    h(1) = 1;
    h(end) = 0.4;

    y_reverb = conv(y, h, 'same');

    app.y_reverb = y_reverb;
    t = (0:length(y_reverb)-1)/fs;
    plot(app.UIAxes4, t, y_reverb);
    grid(app.UIAxes4, 'on'); 
    title(app.UIAxes4, 'Reverb - Tiempo');
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
    if isempty(app.y_original) || isempty(app.fs)
        uialert(app.UIFigure, 'Primero selecciona una música válida.', 'Advertencia');
        return;
    end

    fs = app.fs;
    if numel(fs) > 1
        fs = fs(1);
    end

    app.player = audioplayer(app.y_original, fs);
    play(app.player);
        end

        % Button pushed function: ReproducirButton_2
        function ReproducirButton_2Pushed(app, event)
    if isempty(app.y_comb) || isempty(app.fs)
        uialert(app.UIFigure, 'Aplica el efecto Comb filter antes de reproducir.', 'Advertencia');
        return;
    end

    fs = app.fs;
    if numel(fs) > 1
        fs = fs(1);
    end

    app.player = audioplayer(app.y_comb, fs);
    play(app.player);
        end

        % Button pushed function: ReproducirButton_3
        function ReproducirButton_3Pushed(app, event)
    if isempty(app.y_delay) || isempty(app.fs)
        uialert(app.UIFigure, 'Aplica el efecto Delay antes de reproducir.', 'Advertencia');
        return;
    end

    fs = app.fs;
    if numel(fs) > 1
        fs = fs(1);
    end

    app.player = audioplayer(app.y_delay, fs);
    play(app.player);
        end

        % Button pushed function: ReproducirButton_4
        function ReproducirButton_4Pushed(app, event)
    if isempty(app.y_reverb) || isempty(app.fs)
        uialert(app.UIFigure, 'Aplica el efecto Reverb antes de reproducir.', 'Advertencia');
        return;
    end

    fs = app.fs;
    if numel(fs) > 1
        fs = fs(1);
    end

    app.player = audioplayer(app.y_reverb, fs);
    play(app.player);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
        if ~isempty(app.player) && isplaying(app.player)
        pause(app.player);
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Comb Filter')
            xlabel(app.UIAxes2, 'Tiempo (s)')
            ylabel(app.UIAxes2, 'Amplitud')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Delay')
            xlabel(app.UIAxes3, 'Tiempo (s)')
            ylabel(app.UIAxes3, 'Amplitud')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Reverb')
            xlabel(app.UIAxes4, 'Tiempo (s)')
            ylabel(app.UIAxes4, 'Amplitud')
            zlabel(app.UIAxes4, 'Z')
            app.ReproducirButton = uibutton(app.UIFigure, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.ReproducirButton_2 = uibutton(app.UIFigure, 'push');
            app.ReproducirButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_2Pushed, true);
            app.ReproducirButton_2.Text = 'Reproducir';
            app.ReproducirButton_3 = uibutton(app.UIFigure, 'push');
            app.ReproducirButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_3Pushed, true);
            app.ReproducirButton_3.Text = 'Reproducir';
            app.ReproducirButton_4 = uibutton(app.UIFigure, 'push');
            app.ReproducirButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_4Pushed, true);
            app.ReproducirButton_4.Text = 'Reproducir';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EFECTOSTab = uitab(app.TabGroup);
            app.EFECTOSTab.Title = 'EFECTOS';
            app.CombfilterButton = uibutton(app.EFECTOSTab, 'push');
            app.CombfilterButton.ButtonPushedFcn = createCallbackFcn(app, @CombfilterButtonPushed, true);
            app.CombfilterButton.Text = 'Comb filter';
            app.DelayButton = uibutton(app.EFECTOSTab, 'push');
            app.DelayButton.ButtonPushedFcn = createCallbackFcn(app, @DelayButtonPushed, true);
            app.DelayButton.Text = 'Delay';
            app.ReverbButton = uibutton(app.EFECTOSTab, 'push');
            app.ReverbButton.ButtonPushedFcn = createCallbackFcn(app, @ReverbButtonPushed, true);
            app.ReverbButton.Text = 'Reverb';
            app.Label = uilabel(app.EFECTOSTab);
            app.Label.Text = '🎵';
            app.DropDown = uidropdown(app.EFECTOSTab);
            app.DropDown.Items = {'Musica 1', 'Musica 2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Musica 1';
            app.Image = uiimage(app.EFECTOSTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pausa.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tareita2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end