classdef ECG_PQRST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EXPORTARButton           matlab.ui.control.Button
        IMPORTARButton           matlab.ui.control.Button
        TabGroup                 matlab.ui.container.TabGroup
        SENALORIGINALTab         matlab.ui.container.Tab
        Image2                   matlab.ui.control.Image
        NombreEditField          matlab.ui.control.EditField
        NombreEditFieldLabel     matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        UIAxes6                  matlab.ui.control.UIAxes
        FiltroFIRTab             matlab.ui.container.Tab
        FaseButton_4             matlab.ui.control.Button
        MagnitudButton_4         matlab.ui.control.Button
        RptaImpulsivaButton_2    matlab.ui.control.Button
        FaseButton_2             matlab.ui.control.Button
        MagnitudButton_2         matlab.ui.control.Button
        TantoporunoButton_2      matlab.ui.control.Button
        GenerarFiltroButton      matlab.ui.control.Button
        VentanaDropDown          matlab.ui.control.DropDown
        VentanaDropDownLabel     matlab.ui.control.Label
        TiposDropDown            matlab.ui.control.DropDown
        TiposDropDownLabel       matlab.ui.control.Label
        FHEditField              matlab.ui.control.NumericEditField
        FHEditFieldLabel         matlab.ui.control.Label
        FLEditField              matlab.ui.control.NumericEditField
        FLEditFieldLabel         matlab.ui.control.Label
        OrdenEditField           matlab.ui.control.NumericEditField
        OrdenEditFieldLabel      matlab.ui.control.Label
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        FiltroIIRTab             matlab.ui.container.Tab
        FaseButton_3             matlab.ui.control.Button
        MagnitudButton_3         matlab.ui.control.Button
        RptaImpulsivaButton      matlab.ui.control.Button
        FaseButton               matlab.ui.control.Button
        MagnitudButton           matlab.ui.control.Button
        TantoporunoButton        matlab.ui.control.Button
        TiposDropDown_2          matlab.ui.control.DropDown
        TiposDropDown_2Label     matlab.ui.control.Label
        FHEditField_2            matlab.ui.control.NumericEditField
        FHEditField_2Label       matlab.ui.control.Label
        FLEditField_2            matlab.ui.control.NumericEditField
        FLEditField_2Label       matlab.ui.control.Label
        OrdenEditField_2         matlab.ui.control.NumericEditField
        OrdenEditField_2Label    matlab.ui.control.Label
        GenerarFiltroButton_2    matlab.ui.control.Button
        UIAxes5                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        ONDAPQRSTab              matlab.ui.container.Tab
        BorrarButton             matlab.ui.control.Button
        RESULTADOSTextArea       matlab.ui.control.TextArea
        RESULTADOSTextAreaLabel  matlab.ui.control.Label
        FiltroIIRButton          matlab.ui.control.Button
        FiltroFIRButton          matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        ContextMenu              matlab.ui.container.ContextMenu
        Menu                     matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Property % Description
        y_filtrada
        y_filtrada_iir
        b_iir
        a_iir
        y_final_iir
        x
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IMPORTARButton
        function IMPORTARButtonPushed(app, event)
    global filepath
    % Abre un cuadro de diálogo para seleccionar un archivo
    [filename, path] = uigetfile({'*.mat', 'Archivos MAT (*.mat)'; ...
                                  '*.*', 'Todos los archivos (*.*)'}, ...
                                  'Seleccione un archivo');
           
    filepath = fullfile(path, filename); % Ruta completa del archivo
    data = load(filepath); % Cargar datos del archivo .mat
    
    % Asignar la señal ECG a app.x (asumiendo que la variable se llama 'x' en el .mat)
    app.x = data.x; % Esto es clave para que funcione FiltroIIRButtonPushed
    
    app.NombreEditField.Value = filename; % Mostrar nombre del archivo

    % Valores para la gráfica
    Fs = 500; % Frecuencia de muestreo
    Ts = 1/Fs; % Periodo de muestreo
    tc = length(app.x) * Ts; % Tiempo de captura total
    t = 0:Ts:tc-Ts; % Vector Tiempo
    
    % Graficar
    plot(app.UIAxes6, t, app.x);
    title(app.UIAxes6, 'Señal Original');
    xlabel(app.UIAxes6, 'Tiempo (s)');
    ylabel(app.UIAxes6, 'Amplitud (mV)');   
        end

        % Button pushed function: TantoporunoButton_2
        function TantoporunoButton_2Pushed(app, event)
        
        global filepath x
        
        % Cargar señal desde archivo
        load(filepath); % Variable 'x' debe existir
        Fs = 500; % Frecuencia de muestreo fija
        T = 1 / Fs;
        n = 0:length(x)-1;
        
        % Obtener valores desde la app
        N = app.OrdenEditField.Value;
        fL = app.FLEditField.Value;
        fH = app.FHEditField.Value;
        tipo = app.TiposDropDown.Value;
        ventana_sel = app.VentanaDropDown.Value;
        
        % Normalizar frecuencias a radianes (0 - pi)
        wL = fL / (Fs/2) * pi;
        wH = fH / (Fs/2) * pi;
        
        % Crear ventana (mejorado)
        switch ventana_sel
            case 'Hamming'
                ventana = hamming(N+1)';
            case 'Hanning'
                ventana = hann(N+1)';
            case 'Blackman'
                ventana = blackman(N+1)';
            otherwise
                ventana = ones(1, N+1); % Rectangular
        end
        
        % Crear filtro prototipo
        n_filtro = 0:N;
        switch tipo
            case 'Pasa bajos'
                wc = wH;
                ha = sin(wc*(n_filtro - N/2))./(pi*(n_filtro - N/2));
                ha(N/2 + 1) = wc / pi;
            case 'Pasa altos'
                wc = wL;
                ha = sin(pi*(n_filtro - N/2))./(pi*(n_filtro - N/2)) - sin(wc*(n_filtro - N/2))./(pi*(n_filtro - N/2));
                ha(N/2 + 1) = 1 - wc / pi;
            case 'Pasa bandas'
                ha = (sin(wH*(n_filtro - N/2)) - sin(wL*(n_filtro - N/2))) ./ (pi*(n_filtro - N/2));
                ha(N/2 + 1) = (wH - wL) / pi;
            case 'Rechaza banda'
                ha = sin(pi*(n_filtro - N/2))./(pi*(n_filtro - N/2)) - (sin(wH*(n_filtro - N/2)) - sin(wL*(n_filtro - N/2))) ./ (pi*(n_filtro - N/2));
                ha(N/2 + 1) = 1 - (wH - wL)/pi;
            otherwise
                uialert(app.UIFigure, 'Tipo de filtro no válido.', 'Error');
                return;
        end
        
        % Aplicar ventana
        b = ha .* ventana;
        
        % Filtrar señal
        y = filter(b, 1, x);
        app.y_filtrada = y;

        
        % Calcular respuesta en frecuencia
        [H, FREC] = freqz(b, 1, 8192, Fs);
        
        % Graficar
        plot(app.UIAxes2, FREC, abs(H));
        title(app.UIAxes2, 'Respuesta en Frecuencia FIR');
        xlabel(app.UIAxes2, 'Frecuencia (Hz)');
        ylabel(app.UIAxes2, 'Magnitud');


        end

        % Button pushed function: MagnitudButton_2
        function MagnitudButton_2Pushed(app, event)

        global filepath x
        
        % Cargar señal desde archivo
        load(filepath); % La variable 'x' debe existir
        Fs = 500; % Frecuencia de muestreo fija
        T = 1 / Fs;
        n = 0:length(x)-1;
        
        % Obtener valores desde la app
        N = app.OrdenEditField.Value;
        fL = app.FLEditField.Value;
        fH = app.FHEditField.Value;
        tipo = app.TiposDropDown.Value;
        ventana_sel = app.VentanaDropDown.Value;
        
        % Normalizar frecuencias a radianes (0 - pi)
        wL = fL / (Fs/2) * pi;
        wH = fH / (Fs/2) * pi;
        
        % Crear ventana
        switch ventana_sel
            case 'Hamming'
                ventana = hamming(N+1)';
            case 'Hanning'
                ventana = hann(N+1)';
            case 'Blackman'
                ventana = blackman(N+1)';
            otherwise
                ventana = ones(1, N+1); % Rectangular
        end
        
        % Crear filtro prototipo
        n_filtro = 0:N;
        switch tipo
            case 'Pasa bajos'
                wc = wH;
                ha = sin(wc*(n_filtro - N/2))./(pi*(n_filtro - N/2));
                ha(N/2 + 1) = wc / pi;
            case 'Pasa altos'
                wc = wL;
                ha = sin(pi*(n_filtro - N/2))./(pi*(n_filtro - N/2)) - sin(wc*(n_filtro - N/2))./(pi*(n_filtro - N/2));
                ha(N/2 + 1) = 1 - wc / pi;
            case 'Pasa bandas'
                ha = (sin(wH*(n_filtro - N/2)) - sin(wL*(n_filtro - N/2))) ./ (pi*(n_filtro - N/2));
                ha(N/2 + 1) = (wH - wL) / pi;
            case 'Rechaza banda'
                ha = sin(pi*(n_filtro - N/2))./(pi*(n_filtro - N/2)) - ...
                     (sin(wH*(n_filtro - N/2)) - sin(wL*(n_filtro - N/2))) ./ (pi*(n_filtro - N/2));
                ha(N/2 + 1) = 1 - (wH - wL)/pi;
            otherwise
                uialert(app.UIFigure, 'Tipo de filtro no válido.', 'Error');
                return;
        end
        
        % Aplicar ventana
        b = ha .* ventana;
        
        % Calcular respuesta en frecuencia
        [H, FREC] = freqz(b, 1, 8192, Fs);
        H_dB = 20*log10(abs(H)); % Magnitud en decibelios
        
        % Graficar en dB
        plot(app.UIAxes2, FREC, H_dB, 'LineWidth', 1.5);
        title(app.UIAxes2, 'Respuesta en Frecuencia FIR (Magnitud en dB)');
        xlabel(app.UIAxes2, 'Frecuencia (Hz)');
        ylabel(app.UIAxes2, 'Magnitud (dB)');
        grid(app.UIAxes2, 'on');

            
        end

        % Button pushed function: FaseButton_2
        function FaseButton_2Pushed(app, event)

        global filepath x
    
        % Cargar señal desde archivo
        load(filepath); % La variable 'x' debe existir
        Fs = 500; % Frecuencia de muestreo fija
        T = 1 / Fs;
        n = 0:length(x)-1;
    
        % Obtener valores desde la app
        N = app.OrdenEditField.Value;
        fL = app.FLEditField.Value;
        fH = app.FHEditField.Value;
        tipo = app.TiposDropDown.Value;
        ventana_sel = app.VentanaDropDown.Value;
    
        % Normalizar frecuencias a radianes (0 - pi)
        wL = fL / (Fs/2) * pi;
        wH = fH / (Fs/2) * pi;
    
        % Crear ventana
        switch ventana_sel
            case 'Hamming'
                ventana = hamming(N+1)';
            case 'Hanning'
                ventana = hann(N+1)';
            case 'Blackman'
                ventana = blackman(N+1)';
            otherwise
                ventana = ones(1, N+1); % Rectangular
        end
    
        % Crear filtro prototipo
        n_filtro = 0:N;
        switch tipo
            case 'Pasa bajos'
                wc = wH;
                ha = sin(wc*(n_filtro - N/2))./(pi*(n_filtro - N/2));
                ha(N/2 + 1) = wc / pi;
            case 'Pasa altos'
                wc = wL;
                ha = sin(pi*(n_filtro - N/2))./(pi*(n_filtro - N/2)) - sin(wc*(n_filtro - N/2))./(pi*(n_filtro - N/2));
                ha(N/2 + 1) = 1 - wc / pi;
            case 'Pasa bandas'
                ha = (sin(wH*(n_filtro - N/2)) - sin(wL*(n_filtro - N/2))) ./ (pi*(n_filtro - N/2));
                ha(N/2 + 1) = (wH - wL) / pi;
            case 'Rechaza banda'
                ha = sin(pi*(n_filtro - N/2))./(pi*(n_filtro - N/2)) - ...
                     (sin(wH*(n_filtro - N/2)) - sin(wL*(n_filtro - N/2))) ./ (pi*(n_filtro - N/2));
                ha(N/2 + 1) = 1 - (wH - wL)/pi;
            otherwise
                uialert(app.UIFigure, 'Tipo de filtro no válido.', 'Error');
                return;
        end
    
        % Aplicar ventana
        b = ha .* ventana;
    
        % Calcular respuesta en frecuencia
        [H, FREC] = freqz(b, 1, 8192, Fs);
        FASE = angle(H) * (180/pi); % Fase en grados
    
        % Graficar fase
        plot(app.UIAxes2, FREC, FASE, 'LineWidth', 1.5);
        title(app.UIAxes2, 'Respuesta en Fase FIR');
        xlabel(app.UIAxes2, 'Frecuencia (Hz)');
        ylabel(app.UIAxes2, 'Fase (grados)');
        grid(app.UIAxes2, 'on');

        end

        % Button pushed function: RptaImpulsivaButton_2
        function RptaImpulsivaButton_2Pushed(app, event)

    global filepath x

    % Cargar señal desde archivo
    load(filepath); % La variable 'x' debe existir
    Fs = 500; % Frecuencia de muestreo fija
    T = 1 / Fs;
    n = 0:length(x)-1;

    % Obtener valores desde la app
    N = app.OrdenEditField.Value;
    fL = app.FLEditField.Value;
    fH = app.FHEditField.Value;
    tipo = app.TiposDropDown.Value;
    ventana_sel = app.VentanaDropDown.Value;

    % Normalizar frecuencias a radianes (0 - pi)
    wL = fL / (Fs/2) * pi;
    wH = fH / (Fs/2) * pi;

    % Crear ventana
    switch ventana_sel
        case 'Hamming'
            ventana = hamming(N+1)';
        case 'Hanning'
            ventana = hann(N+1)';
        case 'Blackman'
            ventana = blackman(N+1)';
        otherwise
            ventana = ones(1, N+1); % Rectangular
    end

    % Crear filtro prototipo
    n_filtro = 0:N;
    switch tipo
        case 'Pasa bajos'
            wc = wH;
            ha = sin(wc*(n_filtro - N/2))./(pi*(n_filtro - N/2));
            ha(N/2 + 1) = wc / pi;
        case 'Pasa altos'
            wc = wL;
            ha = sin(pi*(n_filtro - N/2))./(pi*(n_filtro - N/2)) - sin(wc*(n_filtro - N/2))./(pi*(n_filtro - N/2));
            ha(N/2 + 1) = 1 - wc / pi;
        case 'Pasa bandas'
            ha = (sin(wH*(n_filtro - N/2)) - sin(wL*(n_filtro - N/2))) ./ (pi*(n_filtro - N/2));
            ha(N/2 + 1) = (wH - wL) / pi;
        case 'Rechaza banda'
            ha = sin(pi*(n_filtro - N/2))./(pi*(n_filtro - N/2)) - ...
                 (sin(wH*(n_filtro - N/2)) - sin(wL*(n_filtro - N/2))) ./ (pi*(n_filtro - N/2));
            ha(N/2 + 1) = 1 - (wH - wL)/pi;
        otherwise
            uialert(app.UIFigure, 'Tipo de filtro no válido.', 'Error');
            return;
    end

    % Aplicar ventana
    b = ha .* ventana;

    % Respuesta impulsiva (es el propio filtro)
    stem(app.UIAxes2, b, 'filled');
    title(app.UIAxes2, 'Respuesta Impulsiva FIR');
    xlabel(app.UIAxes2, 'n');
    ylabel(app.UIAxes2, 'h[n]');
    grid(app.UIAxes2, 'on');


        end

        % Button pushed function: MagnitudButton_4
        function MagnitudButton_4Pushed(app, event)
        % Parámetros
        fs = 500;                     % Frecuencia de muestreo fija
        N = length(app.y_filtrada);  % Longitud de la señal filtrada
    
        % Cálculo de la FFT
        Y = fft(app.y_filtrada, N);
        f = (0:N-1)*(fs/N);           % Vector de frecuencias
        Y_mag = abs(Y);
    
        % Solo mostrar hasta Nyquist (fs/2)
        mitad = floor(N/2);
        plot(app.UIAxes4, f(1:mitad), Y_mag(1:mitad), 'r' , 'LineWidth', 2);
        grid(app.UIAxes4, 'on');
        title(app.UIAxes4, 'Magnitud de la Señal Filtrada');
        xlabel(app.UIAxes4, 'Frecuencia (Hz)');
        ylabel(app.UIAxes4, '|Y(f)|');
        end

        % Button pushed function: FaseButton_4
        function FaseButton_4Pushed(app, event)
        % Parámetros
        fs = 500;                     % Frecuencia de muestreo
        N = length(app.y_filtrada);  % Longitud de la señal filtrada
    
        % Cálculo de la FFT
        Y = fft(app.y_filtrada, N);
        f = (0:N-1)*(fs/N);           % Vector de frecuencias
        Y_fase = angle(Y);
    
        % Solo hasta Nyquist
        mitad = floor(N/2);
        plot(app.UIAxes4, f(1:mitad), Y_fase(1:mitad), 'r', 'LineWidth', 2);
        grid(app.UIAxes4, 'on');
        title(app.UIAxes4, 'Fase de la Señal Filtrada');
        xlabel(app.UIAxes4, 'Frecuencia (Hz)');
        ylabel(app.UIAxes4, 'Fase (rad)');
        end

        % Button pushed function: TantoporunoButton
        function TantoporunoButtonPushed(app, event)
        global filepath
    load(filepath);  % Carga la señal 'x' del archivo MAT

    % Obtener parámetros
    Fs = 500;
    N = app.OrdenEditField_2.Value;
    TL = app.FLEditField_2.Value;
    TH = app.FHEditField_2.Value;
    tipo = app.TiposDropDown_2.Value;

    % Normalización
    TL_norm = TL / (Fs/2);
    TH_norm = TH / (Fs/2);

    % Validación
    if TL_norm <= 0 || TH_norm <= 0 || TL_norm >= 1 || TH_norm >= 1
        uialert(app.UIFigure, 'Frecuencias deben estar entre 0 y Fs/2', 'Error');
        return;
    end

    % Diseño del filtro según tipo
    switch tipo
        case 'Pasa bajos'
            [b, a] = butter(N, TL_norm, 'low');
        case 'Pasa altos'
            [b, a] = butter(N, TH_norm, 'high');
        case 'Pasa bandas'
            [b, a] = butter(N, [TL_norm TH_norm], 'bandpass');
        case 'Rechaza banda'
            [b, a] = butter(N, [TL_norm TH_norm], 'stop');
        otherwise
            uialert(app.UIFigure, 'Tipo de filtro no reconocido', 'Error');
            return;
    end

    % Guardar coeficientes
    app.b_iir = b;
    app.a_iir = a;

    % Graficar magnitud (Tanto por uno)
    [H, f] = freqz(b, a, 1024, Fs);
    plot(app.UIAxes3, f, abs(H));
    title(app.UIAxes3, 'Magnitud (Tanto por uno)');
    xlabel(app.UIAxes3, 'Frecuencia (Hz)');
    ylabel(app.UIAxes3, '|H(f)|');
    grid(app.UIAxes3, 'on');

    % Aplicar filtro a señal ECG
    y_temp = filtfilt(b, a, x);              % Filtrado sin desfase
    y_norm = y_temp / max(abs(y_temp));      % Normalización
    app.y_filtrada = y_norm * 100;           % Guardar para magnitud/fase
        
        end

        % Button pushed function: MagnitudButton
        function MagnitudButtonPushed(app, event)
    if isempty(app.b_iir) || isempty(app.a_iir)
        uialert(app.UIFigure, 'Primero diseñe el filtro con Tantoporuno', 'Error');
        return;
    end
    
    Fs = 500;
    [H, f] = freqz(app.b_iir, app.a_iir, 1024, Fs);
    
    % ==================================================
    % SEGUNDA GRÁFICA (dB) - EN MagnitudButton
    % ==================================================
    plot(app.UIAxes3, f, 20*log10(abs(H)));
    title(app.UIAxes3, 'Magnitud (dB)');
    xlabel(app.UIAxes3, 'Frecuencia (Hz)');
    ylabel(app.UIAxes3, '|H(f)| (dB)');
    grid(app.UIAxes3, 'on');

        end

        % Button pushed function: FaseButton
        function FaseButtonPushed(app, event)
   if isempty(app.b_iir) || isempty(app.a_iir)
        uialert(app.UIFigure, 'Primero diseñe el filtro con Tantoporuno', 'Error');
        return;
    end
    
    Fs = 500;
    [H, f] = freqz(app.b_iir, app.a_iir, 1024, Fs);
    
    plot(app.UIAxes3, f, unwrap(angle(H)));
    title(app.UIAxes3, 'Respuesta de Fase');
    xlabel(app.UIAxes3, 'Frecuencia (Hz)');
    ylabel(app.UIAxes3, 'Fase (rad)');
    grid(app.UIAxes3, 'on');
        end

        % Button pushed function: RptaImpulsivaButton
        function RptaImpulsivaButtonPushed(app, event)
    if isempty(app.b_iir) || isempty(app.a_iir)
        uialert(app.UIFigure, 'Primero diseñe el filtro con Tantoporuno', 'Error');
        return;
    end
    
    % Respuesta al impulso
    imp_resp = filter(app.b_iir, app.a_iir, [1 zeros(1, 99)]);
    stem(app.UIAxes3, imp_resp, 'filled');
    title(app.UIAxes3, 'Respuesta Impulsiva');
    xlabel(app.UIAxes3, 'Muestras');
    ylabel(app.UIAxes3, 'Amplitud');
    grid(app.UIAxes3, 'on');
        end

        % Button pushed function: MagnitudButton_3
        function MagnitudButton_3Pushed(app, event)
    if isempty(app.y_filtrada)
        uialert(app.UIFigure, 'Primero aplique el filtro con Tantoporuno', 'Error');
        return;
    end

    fs = 500;
    N = length(app.y_filtrada);
    Y = fft(app.y_filtrada, N);
    f = (0:N-1)*(fs/N);
    Y_mag = abs(Y);

    mitad = floor(N/2);
    plot(app.UIAxes5, f(1:mitad), Y_mag(1:mitad), 'r', 'LineWidth', 2);
    title(app.UIAxes5, 'Magnitud de la Señal Filtrada (IIR)');
    xlabel(app.UIAxes5, 'Frecuencia (Hz)');
    ylabel(app.UIAxes5, '|Y(f)|');
    grid(app.UIAxes5, 'on');
        end

        % Button pushed function: FaseButton_3
        function FaseButton_3Pushed(app, event)
    if isempty(app.y_filtrada)
        uialert(app.UIFigure, 'Primero aplique el filtro con Tantoporuno', 'Error');
        return;
    end

    fs = 500;
    N = length(app.y_filtrada);
    Y = fft(app.y_filtrada, N);
    f = (0:N-1)*(fs/N);
    Y_fase = angle(Y);

    mitad = floor(N/2);
    plot(app.UIAxes5, f(1:mitad), Y_fase(1:mitad), 'r', 'LineWidth', 2);
    title(app.UIAxes5, 'Fase de la Señal Filtrada (IIR)');
    xlabel(app.UIAxes5, 'Frecuencia (Hz)');
    ylabel(app.UIAxes5, 'Fase (rad)');
    grid(app.UIAxes5, 'on');
    
        end

        % Button pushed function: FiltroFIRButton
        function FiltroFIRButtonPushed(app, event)
    cla(app.UIAxes);
    
    app.RESULTADOSTextArea.Value = {''};  % ← ESTA LÍNEA es clave

    % --- Cargar señal filtrada ---
    y = app.y_filtrada;  % Señal filtrada
    Fs = 500;
    T = 1 / Fs;
    t = (0:numel(y)-1) / Fs;

    % --- Eliminar tendencia ---
    [p, s, mu] = polyfit(t, y, 9);
    tendencia = polyval(p, t, [], mu);
    ECG = y - tendencia;

    % --- Detectar picos R y S ---
    [ampR, R] = findpeaks(ECG, t, 'MinPeakHeight', 0.7);
    [ampS, S] = findpeaks(-ECG, t, 'MinPeakHeight', 0.7);
    ampS = -ampS;

    % --- Detectar Q ---
    [~, min_loc] = findpeaks(-ECG, t);
    muestra = round(min_loc * Fs);
    Q = min_loc(ECG(muestra) > -0.4 & ECG(muestra) < -0.2);
    ampQ = ECG(round(Q * Fs));

    % --- Detectar ondas P ---
    P = []; ampP = [];
    for i = 1:length(R)
        ventana = t(t > (R(i) - 0.3) & t < R(i));
        senal_ventana = ECG(t > (R(i) - 0.3) & t < R(i));
        [amp_temp, loc_temp] = findpeaks(senal_ventana, ventana, 'MinPeakHeight', 0.25);
        if ~isempty(loc_temp)
            P = [P loc_temp(end)];
            ampP = [ampP amp_temp(end)];
        end
    end

    % --- Detectar ondas T ---
    T_ondas = []; ampT = [];
    for i = 1:length(R)
        ventana1 = t(t > R(i) & t < (R(i) + 0.4));
        senal_ventana = ECG(t > R(i) & t < (R(i) + 0.4));
        [amp_temp1, loc_temp1] = findpeaks(senal_ventana, ventana1, 'MinPeakHeight', 0.11);
        if ~isempty(loc_temp1)
            T_ondas = [T_ondas loc_temp1(1)];
            ampT = [ampT amp_temp1(1)];
        end
    end

    % --- Graficar señal y picos ---
    plot(app.UIAxes, t, ECG);
    title(app.UIAxes, "Complejo PQRST");
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, Q, ECG(round(Q*Fs)), "s", 'LineWidth', 2);
    plot(app.UIAxes, R, ECG(round(R*Fs)), "*", 'LineWidth', 2);
    plot(app.UIAxes, S, ECG(round(S*Fs)), "v", 'LineWidth', 2);
    plot(app.UIAxes, P, ECG(round(P*Fs)), "^", 'LineWidth', 2);
    plot(app.UIAxes, T_ondas, ECG(round(T_ondas*Fs)), "o", 'LineWidth', 2);
    legend(app.UIAxes, "ECG", "Q", "R", "S", "P", "T");

    % --- Calcular Frecuencia Cardíaca ---
    Tins = diff(R);
    Fins = round(60 ./ Tins);
    texto = "";
    for i = 1:length(Fins)
        texto = texto + sprintf('%2d: %2d bpm\n', i, Fins(i));
    end
    TCar = R(end) - R(1);
    FCar = round(((length(R)-1) / TCar) * 60);
    frecuencia = sprintf('\nFrecuencia Cardiaca: %d bpm\n', FCar);

    % --- Análisis: Diagnóstico básico ---
    if FCar < 60
        CDiag = 'El paciente tiene bradicardia';
    elseif FCar >= 60 && FCar < 95
        CDiag = 'El paciente tiene un ritmo cardiaco normal';
    else
        CDiag = 'El paciente tiene taquicardia';
    end

    % --- Arritmia ---
    arritmia = [];
    for i = 1:(length(Tins)-1)
        dT = abs(Tins(i+1) - Tins(i));
        if dT >= 0.004
            arritmia = [arritmia, dT];
        end
    end
    if length(arritmia) >= 1
        arrit = 'El paciente presenta arritmia';
    else
        arrit = 'El paciente no presenta arritmia';
    end

    % --- Amplitud R-S ---
    ampR_S = ampR - ampS;
    dAmp = abs(diff(ampR_S));  % Calcula diferencias entre elementos consecutivos
    falla_amp_RS = dAmp(dAmp > 0.05);  % Filtra las diferencias mayores a 0.05
    
    if ~isempty(falla_amp_RS)
        fallaRS = 'El paciente presenta falla de amplitud R-S';
    else
        fallaRS = 'El paciente no presenta falla de amplitud R-S';
    end


    % --- Intervalo PR ---
    PR_intervals = [];
    for i = 1:length(P)
        PR = R(i) - P(i);
        PR_intervals = [PR_intervals PR];
    end
    falla_PR = PR_intervals(PR_intervals < 0.12 | PR_intervals > 0.2);
    if length(falla_PR) >= 1
        fallaPR = sprintf('El paciente presenta fallas en el intervalo PR (%d ciclos fuera de rango).', length(falla_PR));
    else
        fallaPR = 'El paciente no presenta fallas en el intervalo PR.';
    end

    % --- Intervalo QRS ---
    QRS_intervalos = [];
    for i = 1:length(R)
        idx_R = find(t == R(i));
        for j = idx_R:-1:2
            if ECG(j) < ECG(j-1)
                inicio_Q = t(j);
                break;
            end
        end
        for j = idx_R:length(t)-1
            if ECG(j) > ECG(j+1)
                fin_S = t(j);
                break;
            end
        end
        QRS_interval = fin_S - inicio_Q;
        QRS_intervalos = [QRS_intervalos QRS_interval];
    end
    falla_QRS = QRS_intervalos(QRS_intervalos < 0.06 | QRS_intervalos > 0.12);
    if length(falla_QRS) >= 1
        fallaQRS = sprintf('El paciente presenta fallas en el intervalo QRS (%d ciclos fuera de rango).', length(falla_QRS));
    else
        fallaQRS = 'El paciente no presenta fallas en el intervalo QRS.';
    end

    app.RESULTADOSTextArea.Value = sprintf([ ...
        '🩺 Análisis del ECG\n' ...
        '-------------------------\n' ...
        '🔢 Frecuencias Cardíacas Instantáneas:\n%s\n' ...
        '❤️ Frecuencia Cardíaca Promedio: %0.2f bpm\n' ...
        '📋 Diagnóstico General: %s\n' ...
        '📈 Ritmo Cardíaco: %s\n' ...
        '🔁 Variabilidad del Ritmo (Arritmia): %s\n' ...
        '📉 Análisis Amplitud R-S: %s\n' ...
        '⏱️ Intervalo PR: %s\n' ...
        '⏱️ Intervalo QRS: %s\n'], ...
        texto, FCar, CDiag, arrit, fallaRS, fallaPR, fallaQRS);


        end

        % Button pushed function: FiltroIIRButton
        function FiltroIIRButtonPushed(app, event)
    cla(app.UIAxes);
    
    app.RESULTADOSTextArea.Value = {''};  % ← ESTA LÍNEA es clave

    % --- Cargar señal filtrada con IIR ---
    y = app.y_filtrada;  % Señal filtrada con IIR
    Fs = 500;
    t = (0:numel(y)-1) / Fs;

    % --- Eliminar tendencia ---
    [p, ~, mu] = polyfit(t, y, 9);
    tendencia = polyval(p, t, [], mu);
    ECG = y - tendencia;

% --- Detectar picos R y S ---
[ampR, R] = findpeaks(ECG, t, 'MinPeakHeight', 0.7);
[ampS, S] = findpeaks(-ECG, t, 'MinPeakHeight', 0.7);
ampS = -ampS;

% --- Detectar Q ---
[~, min_loc] = findpeaks(-ECG, t);
muestra = round(min_loc * Fs);
Q = min_loc(ECG(muestra) > -0.4 & ECG(muestra) < -0.2);
ampQ = ECG(round(Q * Fs));

% --- Detectar ondas P ---
P = []; ampP = [];
for i = 1:length(R)
    ventana_idx = find(t > (R(i) - 0.3) & t < R(i));
    if length(ventana_idx) >= 3
        ventana = t(ventana_idx);
        senal_ventana = ECG(ventana_idx);
        [amp_temp, loc_temp] = findpeaks(senal_ventana, ventana, 'MinPeakHeight', 0.25);
        if ~isempty(loc_temp)
            P = [P loc_temp(end)];
            ampP = [ampP amp_temp(end)];
        end
    end
end

% --- Detectar ondas T ---
T_ondas = []; ampT = [];
for i = 1:length(R)
    ventana_idx = find(t > R(i) & t < (R(i) + 0.4));
    if length(ventana_idx) >= 3
        ventana = t(ventana_idx);
        senal_ventana = ECG(ventana_idx);
        [amp_temp1, loc_temp1] = findpeaks(senal_ventana, ventana, 'MinPeakHeight', 0.11);
        if ~isempty(loc_temp1)
            T_ondas = [T_ondas loc_temp1(1)];
            ampT = [ampT amp_temp1(1)];
        end
    end
end

    % --- Graficar señal y picos ---
    plot(app.UIAxes, t, ECG);
    title(app.UIAxes, "Complejo PQRST - Filtro IIR");
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, Q, ECG(round(Q*Fs)), "s", 'LineWidth', 2);
    plot(app.UIAxes, R, ECG(round(R*Fs)), "*", 'LineWidth', 2);
    plot(app.UIAxes, S, ECG(round(S*Fs)), "v", 'LineWidth', 2);
    plot(app.UIAxes, P, ECG(round(P*Fs)), "^", 'LineWidth', 2);
    plot(app.UIAxes, T_ondas, ECG(round(T_ondas*Fs)), "o", 'LineWidth', 2);
    legend(app.UIAxes, "ECG", "Q", "R", "S", "P", "T");

    % --- Calcular Frecuencia Cardíaca ---
    Tins = diff(R);
    Fins = round(60 ./ Tins);
    texto = "";
    for i = 1:length(Fins)
        texto = texto + sprintf('%2d: %2d bpm\n', i, Fins(i));
    end
    TCar = R(end) - R(1);
    FCar = round(((length(R)-1) / TCar) * 60);
    frecuencia = sprintf('\nFrecuencia Cardiaca: %d bpm\n', FCar);

    % --- Diagnóstico ---
    if FCar < 60
        CDiag = 'El paciente tiene bradicardia';
    elseif FCar >= 60 && FCar < 95
        CDiag = 'El paciente tiene un ritmo cardiaco normal';
    else
        CDiag = 'El paciente tiene taquicardia';
    end

   % --- Arritmia ---
arritmia = [];
for i = 1:(length(Tins)-1)
    dT = abs(Tins(i+1) - Tins(i));
    if dT >= 0.004
        arritmia = [arritmia, dT];
    end
end
if length(arritmia) >= 1
    arrit = 'El paciente presenta arritmia';
else
    arrit = 'El paciente no presenta arritmia';
end

% --- Amplitud R-S ---
min_len = min(length(ampR), length(ampS));
if min_len > 1
    ampR_S = ampR(1:min_len) - ampS(1:min_len);
    dAmp = abs(diff(ampR_S));
    falla_amp_RS = dAmp(dAmp > 0.05);
    if ~isempty(falla_amp_RS)
        fallaRS = 'El paciente presenta falla de amplitud R-S';
    else
        fallaRS = 'El paciente no presenta falla de amplitud R-S';
    end
else
    fallaRS = 'Datos insuficientes para evaluar la amplitud R-S';
end
    % --- Intervalo PR ---
    PR_intervals = [];
    for i = 1:length(P)
        PR = R(i) - P(i);
        PR_intervals = [PR_intervals PR];
    end
    falla_PR = PR_intervals(PR_intervals < 0.12 | PR_intervals > 0.2);
    if length(falla_PR) >= 1
        fallaPR = sprintf('El paciente presenta fallas en el intervalo PR (%d ciclos fuera de rango).', length(falla_PR));
    else
        fallaPR = 'El paciente no presenta fallas en el intervalo PR.';
    end

    % --- Intervalo QRS ---
QRS_intervalos = [];

for i = 1:length(R)
    idx_R = find(t == R(i));
    
    % Inicializar
    inicio_Q = NaN;
    fin_S = NaN;

    % Buscar Q (inicio)
    for j = idx_R:-1:2
        if ECG(j) < ECG(j-1)
            inicio_Q = t(j);
            break;
        end
    end

    % Buscar S (fin)
    for j = idx_R:length(t)-1
        if ECG(j) > ECG(j+1)
            fin_S = t(j);
            break;
        end
    end

    % Verificar que se encontró Q y S antes de guardar
    if ~isnan(inicio_Q) && ~isnan(fin_S)
        QRS_interval = fin_S - inicio_Q;
        QRS_intervalos = [QRS_intervalos, QRS_interval];
    end
end

% Analizar fallas
falla_QRS = QRS_intervalos(QRS_intervalos < 0.06 | QRS_intervalos > 0.12);
if ~isempty(falla_QRS)
    fallaQRS = sprintf('El paciente presenta fallas en el intervalo QRS (%d ciclos fuera de rango).', length(falla_QRS));
else
    fallaQRS = 'El paciente no presenta fallas en el intervalo QRS.';
end

    % --- Mostrar resultados ---
    app.RESULTADOSTextArea.Value = sprintf([ ...
        '🩺 Análisis del ECG - Filtro IIR\n' ...
        '-------------------------\n' ...
        '🔢 Frecuencias Cardíacas Instantáneas:\n%s\n' ...
        '❤️ Frecuencia Cardíaca Promedio: %0.2f bpm\n' ...
        '📋 Diagnóstico General: %s\n' ...
        '📈 Ritmo Cardíaco: %s\n' ...
        '🔁 Variabilidad del Ritmo (Arritmia): %s\n' ...
        '📉 Análisis Amplitud R-S: %s\n' ...
        '⏱️ Intervalo PR: %s\n' ...
        '⏱️ Intervalo QRS: %s\n'], ...
        texto, FCar, CDiag, arrit, fallaRS, fallaPR, fallaQRS);

        end

        % Button pushed function: EXPORTARButton
        function EXPORTARButtonPushed(app, event)
 
  % Obtener contenido del TextArea de resultados
resultados = app.RESULTADOSTextArea.Value;

% Separar líneas del TextArea
if iscell(resultados)
    lineas = resultados;
else
    lineas = splitlines(resultados);
end

% Crear tabla para exportar
fila = 1;
datos = strings(length(lineas), 2);  % columna 1: Etiqueta, columna 2: Valor

for i = 1:length(lineas)
    linea = strtrim(lineas{i});
    if isempty(linea)
        continue;
    end

    % Dividir por ':' si existe
    partes = split(linea, ':');
    if length(partes) >= 2
        datos(fila, 1) = strtrim(partes{1});
        datos(fila, 2) = strtrim(join(partes(2:end), ':'));
        fila = fila + 1;
    else
        % Guardar línea como título o sin formato
        datos(fila, 1) = linea;
        datos(fila, 2) = "";
        fila = fila + 1;
    end
end

% Recortar filas vacías
datos = datos(1:fila-1, :);

% Convertir a tabla
tabla = table(datos(:,1), datos(:,2), 'VariableNames', {'Descripcion', 'Valor'});

% Diálogo para elegir ubicación y nombre del archivo
[archivo, ruta] = uiputfile({'*.xlsx','Archivo Excel (*.xlsx)'}, 'Guardar análisis como...', 'Analisis_ECG.xlsx');

if isequal(archivo, 0) || isequal(ruta, 0)
    % Usuario canceló
    return;
end

% Guardar en Excel
try
    writetable(tabla, fullfile(ruta, archivo));
    uialert(app.UIFigure, sprintf('¡Archivo exportado con éxito!\n%s', fullfile(ruta, archivo)), '✅ Exportación exitosa');
catch ME
    uialert(app.UIFigure, sprintf('Error al exportar:\n%s', ME.message), '❌ Error');
end
        
        end

        % Button pushed function: BorrarButton
        function BorrarButtonPushed(app, event)
    % Borra el gráfico
    cla(app.UIAxes);
    
    % Borra todo el contenido de la TextArea correctamente
    app.RESULTADOSTextArea.Value = {''};  % ← ESTA LÍNEA es clave
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SENALORIGINALTab = uitab(app.TabGroup);
            app.SENALORIGINALTab.Title = 'SENAL ORIGINAL';
            app.UIAxes6 = uiaxes(app.SENALORIGINALTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Image = uiimage(app.SENALORIGINALTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ekg-2069872_1280-1200x1088-1.png');
            app.NombreEditFieldLabel = uilabel(app.SENALORIGINALTab);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.SENALORIGINALTab, 'text');
            app.Image2 = uiimage(app.SENALORIGINALTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'U.png');
            app.FiltroFIRTab = uitab(app.TabGroup);
            app.FiltroFIRTab.Title = 'Filtro FIR';
            app.UIAxes2 = uiaxes(app.FiltroFIRTab);
            title(app.UIAxes2, 'Filtro FIR')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.FiltroFIRTab);
            title(app.UIAxes4, 'Resultado')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.OrdenEditFieldLabel = uilabel(app.FiltroFIRTab);
            app.OrdenEditFieldLabel.Text = 'Orden';
            app.OrdenEditField = uieditfield(app.FiltroFIRTab, 'numeric');
            app.OrdenEditField.Value = 80;
            app.FLEditFieldLabel = uilabel(app.FiltroFIRTab);
            app.FLEditFieldLabel.Text = 'FL';
            app.FLEditField = uieditfield(app.FiltroFIRTab, 'numeric');
            app.FHEditFieldLabel = uilabel(app.FiltroFIRTab);
            app.FHEditFieldLabel.Text = 'FH';
            app.FHEditField = uieditfield(app.FiltroFIRTab, 'numeric');
            app.FHEditField.Value = 40;
            app.TiposDropDownLabel = uilabel(app.FiltroFIRTab);
            app.TiposDropDownLabel.Text = 'Tipos';
            app.TiposDropDown = uidropdown(app.FiltroFIRTab);
            app.TiposDropDown.Items = {'Pasa bajos', 'Pasa altos', 'Pasa bandas', 'Rechaza banda'};
            app.TiposDropDown.Value = 'Pasa bajos';
            app.VentanaDropDownLabel = uilabel(app.FiltroFIRTab);
            app.VentanaDropDownLabel.Text = 'Ventana';
            app.VentanaDropDown = uidropdown(app.FiltroFIRTab);
            app.VentanaDropDown.Items = {'Rectangular', 'Barlett', 'Hanning', 'Hamming', 'Blackman'};
            app.VentanaDropDown.Value = 'Rectangular';
            app.GenerarFiltroButton = uibutton(app.FiltroFIRTab, 'push');
            app.GenerarFiltroButton.Text = 'Generar Filtro';
            app.TantoporunoButton_2 = uibutton(app.FiltroFIRTab, 'push');
            app.TantoporunoButton_2.ButtonPushedFcn = createCallbackFcn(app, @TantoporunoButton_2Pushed, true);
            app.TantoporunoButton_2.Text = 'Tanto por uno';
            app.MagnitudButton_2 = uibutton(app.FiltroFIRTab, 'push');
            app.MagnitudButton_2.ButtonPushedFcn = createCallbackFcn(app, @MagnitudButton_2Pushed, true);
            app.MagnitudButton_2.Text = 'Magnitud';
            app.FaseButton_2 = uibutton(app.FiltroFIRTab, 'push');
            app.FaseButton_2.ButtonPushedFcn = createCallbackFcn(app, @FaseButton_2Pushed, true);
            app.FaseButton_2.Text = 'Fase';
            app.RptaImpulsivaButton_2 = uibutton(app.FiltroFIRTab, 'push');
            app.RptaImpulsivaButton_2.ButtonPushedFcn = createCallbackFcn(app, @RptaImpulsivaButton_2Pushed, true);
            app.RptaImpulsivaButton_2.Text = 'Rpta Impulsiva';
            app.MagnitudButton_4 = uibutton(app.FiltroFIRTab, 'push');
            app.MagnitudButton_4.ButtonPushedFcn = createCallbackFcn(app, @MagnitudButton_4Pushed, true);
            app.MagnitudButton_4.Text = 'Magnitud';
            app.FaseButton_4 = uibutton(app.FiltroFIRTab, 'push');
            app.FaseButton_4.ButtonPushedFcn = createCallbackFcn(app, @FaseButton_4Pushed, true);
            app.FaseButton_4.Text = 'Fase';
            app.FiltroIIRTab = uitab(app.TabGroup);
            app.FiltroIIRTab.Title = 'Filtro IIR';
            app.UIAxes3 = uiaxes(app.FiltroIIRTab);
            title(app.UIAxes3, 'Filtro IIR')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes5 = uiaxes(app.FiltroIIRTab);
            title(app.UIAxes5, 'Resultado')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.GenerarFiltroButton_2 = uibutton(app.FiltroIIRTab, 'push');
            app.GenerarFiltroButton_2.Text = 'Generar Filtro';
            app.OrdenEditField_2Label = uilabel(app.FiltroIIRTab);
            app.OrdenEditField_2Label.Text = 'Orden';
            app.OrdenEditField_2 = uieditfield(app.FiltroIIRTab, 'numeric');
            app.OrdenEditField_2.Value = 5;
            app.FLEditField_2Label = uilabel(app.FiltroIIRTab);
            app.FLEditField_2Label.Text = 'FL';
            app.FLEditField_2 = uieditfield(app.FiltroIIRTab, 'numeric');
            app.FLEditField_2.Value = 0.1;
            app.FHEditField_2Label = uilabel(app.FiltroIIRTab);
            app.FHEditField_2Label.Text = 'FH';
            app.FHEditField_2 = uieditfield(app.FiltroIIRTab, 'numeric');
            app.FHEditField_2.Value = 40;
            app.TiposDropDown_2Label = uilabel(app.FiltroIIRTab);
            app.TiposDropDown_2Label.Text = 'Tipos';
            app.TiposDropDown_2 = uidropdown(app.FiltroIIRTab);
            app.TiposDropDown_2.Items = {'Pasa bajos', 'Pasa altos', 'Pasa bandas', 'Rechaza banda'};
            app.TiposDropDown_2.Value = 'Pasa bajos';
            app.TantoporunoButton = uibutton(app.FiltroIIRTab, 'push');
            app.TantoporunoButton.ButtonPushedFcn = createCallbackFcn(app, @TantoporunoButtonPushed, true);
            app.TantoporunoButton.Text = 'Tanto por uno';
            app.MagnitudButton = uibutton(app.FiltroIIRTab, 'push');
            app.MagnitudButton.ButtonPushedFcn = createCallbackFcn(app, @MagnitudButtonPushed, true);
            app.MagnitudButton.Text = 'Magnitud';
            app.FaseButton = uibutton(app.FiltroIIRTab, 'push');
            app.FaseButton.ButtonPushedFcn = createCallbackFcn(app, @FaseButtonPushed, true);
            app.FaseButton.Text = 'Fase';
            app.RptaImpulsivaButton = uibutton(app.FiltroIIRTab, 'push');
            app.RptaImpulsivaButton.ButtonPushedFcn = createCallbackFcn(app, @RptaImpulsivaButtonPushed, true);
            app.RptaImpulsivaButton.Text = 'Rpta Impulsiva';
            app.MagnitudButton_3 = uibutton(app.FiltroIIRTab, 'push');
            app.MagnitudButton_3.ButtonPushedFcn = createCallbackFcn(app, @MagnitudButton_3Pushed, true);
            app.MagnitudButton_3.Text = 'Magnitud';
            app.FaseButton_3 = uibutton(app.FiltroIIRTab, 'push');
            app.FaseButton_3.ButtonPushedFcn = createCallbackFcn(app, @FaseButton_3Pushed, true);
            app.FaseButton_3.Text = 'Fase';
            app.ONDAPQRSTab = uitab(app.TabGroup);
            app.ONDAPQRSTab.Title = 'ONDA PQRS';
            app.UIAxes = uiaxes(app.ONDAPQRSTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FiltroFIRButton = uibutton(app.ONDAPQRSTab, 'push');
            app.FiltroFIRButton.ButtonPushedFcn = createCallbackFcn(app, @FiltroFIRButtonPushed, true);
            app.FiltroFIRButton.Text = 'Filtro FIR';
            app.FiltroIIRButton = uibutton(app.ONDAPQRSTab, 'push');
            app.FiltroIIRButton.ButtonPushedFcn = createCallbackFcn(app, @FiltroIIRButtonPushed, true);
            app.FiltroIIRButton.Text = 'Filtro IIR';
            app.RESULTADOSTextAreaLabel = uilabel(app.ONDAPQRSTab);
            app.RESULTADOSTextAreaLabel.Text = 'RESULTADOS';
            app.RESULTADOSTextArea = uitextarea(app.ONDAPQRSTab);
            app.BorrarButton = uibutton(app.ONDAPQRSTab, 'push');
            app.BorrarButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarButtonPushed, true);
            app.BorrarButton.Text = 'Borrar';
            app.IMPORTARButton = uibutton(app.UIFigure, 'push');
            app.IMPORTARButton.ButtonPushedFcn = createCallbackFcn(app, @IMPORTARButtonPushed, true);
            app.IMPORTARButton.Text = 'IMPORTAR';
            app.EXPORTARButton = uibutton(app.UIFigure, 'push');
            app.EXPORTARButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTARButtonPushed, true);
            app.EXPORTARButton.Text = 'EXPORTAR';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.TantoporunoButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECG_PQRST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end